classdef DirectionalFactorPolarPlots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmartPolarPlotsUIFigure         matlab.ui.Figure
        OpenExternalFigureWindowforSavingCopyingImageButton  matlab.ui.control.Button
        Function6Button                 matlab.ui.control.StateButton
        Function5Button                 matlab.ui.control.StateButton
        Function6EditField              matlab.ui.control.EditField
        Function5EditField              matlab.ui.control.EditField
        PlotFunctionLamp                matlab.ui.control.Lamp
        PlotTemplateLamp                matlab.ui.control.Lamp
        PlotFunctionButton              matlab.ui.control.Button
        GeneratePlotTemplateButton      matlab.ui.control.Button
        Function4Button                 matlab.ui.control.StateButton
        Function2Button                 matlab.ui.control.StateButton
        Function3Button                 matlab.ui.control.StateButton
        Function1Button                 matlab.ui.control.StateButton
        Function4EditField              matlab.ui.control.EditField
        Function3EditField              matlab.ui.control.EditField
        Function2EditField              matlab.ui.control.EditField
        Function1EditField              matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        PlotSetupTab                    matlab.ui.container.Tab
        DiscardRedundantLamp            matlab.ui.control.Lamp
        DiscardRedundantMirrorsButton   matlab.ui.control.StateButton
        DefaultSettingsModesPanel       matlab.ui.container.Panel
        Tiered360Button_2               matlab.ui.control.Button
        Tiered1802Button                matlab.ui.control.Button
        Tiered180Button                 matlab.ui.control.Button
        Tiered3602Button                matlab.ui.control.Button
        Full180Button                   matlab.ui.control.Button
        Full360Button                   matlab.ui.control.Button
        HelperLineLamp                  matlab.ui.control.Lamp
        Hyperlink                       matlab.ui.control.Hyperlink
        HalfPowerHelperLineButton       matlab.ui.control.StateButton
        dBScaleMaximumSpinner           matlab.ui.control.Spinner
        dBScaleMaximumSpinnerLabel      matlab.ui.control.Label
        dBScaleMinimumSpinner           matlab.ui.control.Spinner
        dBScaleMinimumSpinnerLabel      matlab.ui.control.Label
        RadialLabelLocationButtonGroup  matlab.ui.container.ButtonGroup
        RightButton                     matlab.ui.control.RadioButton
        CenterButton                    matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        RadialLabelValueOneSidedBWEditField  matlab.ui.control.EditField
        RadialLabelValueOneSidedBWEditFieldLabel  matlab.ui.control.Label
        RadialLabelValuesDecreasingOrderEditField  matlab.ui.control.EditField
        RadialLabelValuesDecreasingOrderEditFieldLabel  matlab.ui.control.Label
        YMinimumSpinner                 matlab.ui.control.Spinner
        YMinimumSpinnerLabel            matlab.ui.control.Label
        ScaleFactorButtonGroup          matlab.ui.container.ButtonGroup
        dB_Scale20Button                matlab.ui.control.RadioButton
        dB_Scale10Button                matlab.ui.control.RadioButton
        ThetaLabelStepValueButtonGroup  matlab.ui.container.ButtonGroup
        ThetaStep60Button               matlab.ui.control.RadioButton
        ThetaStep45Button               matlab.ui.control.RadioButton
        ThetaStep30Button               matlab.ui.control.RadioButton
        ThetaStep15Button               matlab.ui.control.RadioButton
        ThetaStep10Button               matlab.ui.control.RadioButton
        ThetaStep5Button                matlab.ui.control.RadioButton
        MaxThetaSpinner                 matlab.ui.control.Spinner
        MaxThetaSpinnerLabel            matlab.ui.control.Label
        PlotStyleButtonGroup            matlab.ui.container.ButtonGroup
        LinearScaleButton               matlab.ui.control.RadioButton
        dBScaleButton                   matlab.ui.control.RadioButton
        ImportDataTab                   matlab.ui.container.Tab
        Hyperlink_2                     matlab.ui.control.Hyperlink
        PlotImportedDataSwitch          matlab.ui.control.Switch
        PlotImportedDataSwitchLabel     matlab.ui.control.Label
        excelImportedLamp               matlab.ui.control.Lamp
        csvImportedLamp                 matlab.ui.control.Lamp
        matImportedLamp                 matlab.ui.control.Lamp
        SelectmatFiletoImportButton     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SelectCSVFiletoImportButton     matlab.ui.control.Button
        SelectExcelFiletoImportButton   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dB_Max 
        dB_Min
        MaxTheta
        dB_ScaleFactor
        importTemplateRead = 0 
        plotImportedData 
        discardRedundant 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratePlotTemplateButton
        function GeneratePlotTemplateButtonPushed(app, event)
            cla(app.UIAxes)
            plot(app.UIAxes, 0, 0);            
            hold(app.UIAxes, 'on');
            ylim(app.UIAxes,[-(app.YMinimumSpinner.Value+0.1), 1.1])
            xlim(app.UIAxes,[-1.1, 1.1])
            pbaspect(app.UIAxes,[2.2/(1.1+0.1+app.YMinimumSpinner.Value) 1 1])    
            if app.LeftButton.Value
                YAxisLocation = 'left';
            elseif app.CenterButton.Value
                YAxisLocation = 'origin';
            else
                YAxisLocation = 'right';
            end
            app.UIAxes.YAxisLocation = YAxisLocation;
            app.UIAxes.YAxis.Color = 'k';
            app.UIAxes.YAxis.FontWeight = 'bold';
            app.UIAxes.YAxis.FontSize = 14;
            app.UIAxes.Layer = 'top';
            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';
            app.UIAxes.YTickMode = 'manual';
            
            %capture user defined Max and Min dB scale value:
            dB_Max = app.dBScaleMaximumSpinner.Value;
            dB_Min = app.dBScaleMinimumSpinner.Value;
            app.dB_Max = dB_Max;
            app.dB_Min = dB_Min;
            
            OneSidedBWs = str2num(char(app.RadialLabelValueOneSidedBWEditField.Value)); 
            if app.LinearScaleButton.Value  % if linear mode:
                Labels = str2num(char(app.RadialLabelValuesDecreasingOrderEditField.Value));
                app.UIAxes.YTick = [0 flip(Labels)];
                title(app.UIAxes,'Linear Directivity');
            else                            % else dB mode:
                Labels_dB = str2num(char(app.RadialLabelValuesDecreasingOrderEditField.Value));
                Labels = dB2LinScaling4Plots(Labels_dB,dB_Min, dB_Max);
                app.UIAxes.YTick = [flip(Labels)];
                title(app.UIAxes,'dB Directivity');
            end
            % Capture user defined ThetaStep value.
            ThetaStep = 30;
            if app.ThetaStep5Button.Value
                ThetaStep = 5;
            elseif app.ThetaStep10Button.Value
                ThetaStep = 10;
            elseif app.ThetaStep15Button.Value
                ThetaStep = 15;
            elseif app.ThetaStep30Button.Value
                ThetaStep = 30;
            elseif app.ThetaStep45Button.Value
                ThetaStep = 45;
            else
                ThetaStep = 60;            
            end
            % capture user defined MaxTheta value.
            MaxTheta = app.MaxThetaSpinner.Value;
            app.MaxTheta = MaxTheta;
            
            % define ThetaSweep based on user input.
            ThetaSweep = -MaxTheta:ThetaStep:MaxTheta;
            
            three_dBLineDrawn = -69;
            if app.HalfPowerHelperLineButton.Value
                three_dBLineDrawn = 0;
            end
            
            dB_ScaleFactor = -1;
            LinearHalfPowerValue = -1;
            if app.dB_Scale10Button.Value
                dB_ScaleFactor = 10;
                LinearHalfPowerValue = 1/2;
            end
            if app.dB_Scale20Button.Value
                dB_ScaleFactor = 20;
                LinearHalfPowerValue = 1/sqrt(2);
            end
            app.dB_ScaleFactor = dB_ScaleFactor;
            %generate the plot template.
            for k = 1:length(Labels)
                degreeRange = -OneSidedBWs(k):1:OneSidedBWs(k);
                [xRange, yRange] = pol2cart(deg2rad(90+degreeRange), Labels(k));
                plot(app.UIAxes, xRange, yRange, 'Color','#848482');


                % if dB Scale:
                if app.dBScaleButton.Value
                    if three_dBLineDrawn == 0
                        if app.HalfPowerHelperLineButton.Value & (Labels(k) > dB2LinScaling4Plots(-3,dB_Min, dB_Max)) & (Labels(k+1) < dB2LinScaling4Plots(-3, dB_Min, dB_Max))
                            [xRange3db, yRange3db] = pol2cart(deg2rad(90+degreeRange), dB2LinScaling4Plots(-3, dB_Min, dB_Max))
                            plot(app.UIAxes, xRange3db, yRange3db, 'Color', '#848482', 'LineStyle', '--')
                            three_dBLineDrawn = 1;
                        end                                                            
                    end
                    if strcmp('origin',YAxisLocation)
                        dB_labelsText = text(app.UIAxes,0.01, 0.005 + dB2LinScaling4Plots(Labels_dB(k),dB_Min, dB_Max), 0.1, num2str(Labels_dB(k)));
                    end                
                    if strcmp('right',YAxisLocation)
                        dB_labelsText = text(app.UIAxes,1, 0.002 + dB2LinScaling4Plots(Labels_dB(k),dB_Min, dB_Max), 0.1, num2str(Labels_dB(k)));    
                    end
                    if strcmp('left',YAxisLocation)
                        dB_labelsText = text(app.UIAxes, -1.1, 0.002 + dB2LinScaling4Plots(Labels_dB(k),dB_Min, dB_Max), 0.1, num2str(Labels_dB(k)));    
                    end
                    dB_labelsText.Color = 'k';
                    dB_labelsText.FontSize = 16;
                    dB_labelsText.FontWeight = 'bold';
                else % if linear scale
                    if three_dBLineDrawn == 0
                        if app.HalfPowerHelperLineButton.Value & (Labels(k)>0.707) & (Labels(k+1)< 0.707)
                            [xRange3db, yRange3db] = pol2cart(deg2rad(90+degreeRange), LinearHalfPowerValue)
                            plot(app.UIAxes, xRange3db, yRange3db, 'Color', '#848482', 'LineStyle', '--')
                            three_dBLineDrawn = 1;
                        end
                    end
                    
                    if strcmp('origin',YAxisLocation)
                        labelsText = text(app.UIAxes, 0.01, 0.005 + (Labels(k)), 0.1, num2str(Labels(k)));
                    end                
                    if strcmp('right',YAxisLocation)
                        labelsText = text(app.UIAxes, 1, 0.002 + (Labels(k)), 0.1, num2str(Labels(k)));    
                    end
                    if strcmp('left',YAxisLocation)
                        labelsText = text(app.UIAxes, -1.1, 0.002 + (Labels(k)), 0.1, num2str(Labels(k)));    
                    end
                    labelsText.Color = 'k';
                    labelsText.FontSize = 16;
                    labelsText.FontWeight = 'bold';                                                        
                end
                
                for thetaSweep = ThetaSweep
                    % detect if the current thetaSweep value falls within the degreeRange of the current k index.
                    if length(degreeRange(degreeRange==thetaSweep)) 
                        % remove current thetaSweep from ThetaSweep so it doesn't
                        % get repeated later!
                        idx = ismember(ThetaSweep, [thetaSweep]);
                        ThetaSweep(idx) = [];
                        % plot the theta line:
                        radiusRange = linspace(0, Labels(k), 100);
                        [xRange2, yRange2] = pol2cart(deg2rad(90+thetaSweep), radiusRange);
                        plot(app.UIAxes, xRange2, yRange2, 'Color', '#848482');
                        txt = num2str(thetaSweep)+"°";
                        if thetaSweep == -180
                            
                        elseif thetaSweep == 180
                            t = text(app.UIAxes, xRange2(end)-0.03, yRange2(end)-0.02, txt); 
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        elseif thetaSweep == 0
                            t = text(app.UIAxes, xRange2(end)-0.01, yRange2(end)+0.03, txt);  
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        elseif thetaSweep < 0                   
                            t = text(app.UIAxes, xRange2(end)+0.02, yRange2(end)+0.01, txt);   
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        else                  
                            t = text(app.UIAxes, xRange2(end)-0.065, yRange2(end)+0.01, txt);    
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        end
                                                                                                                                                                                                                                                                                                              
                    end                
                end                
            end
            legend(app.UIAxes, 'off')
            
            
            app.PlotTemplateLamp.Enable = 1;
            app.PlotFunctionLamp.Enable = 0;
            
        end

        % Value changed function: MaxThetaSpinner
        function MaxThetaSpinnerValueChanged(app, event)
            value = app.MaxThetaSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: 
        % RadialLabelValuesDecreasingOrderEditField
        function RadialLabelValuesDecreasingOrderEditFieldValueChanged(app, event)
            value = app.RadialLabelValuesDecreasingOrderEditField.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: 
        % RadialLabelValueOneSidedBWEditField
        function RadialLabelValueOneSidedBWEditFieldValueChanged(app, event)
            value = app.RadialLabelValueOneSidedBWEditField.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Selection changed function: RadialLabelLocationButtonGroup
        function RadialLabelLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.RadialLabelLocationButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: HalfPowerHelperLineButton
        function HalfPowerHelperLineButtonValueChanged(app, event)
            value = app.HalfPowerHelperLineButton.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            if value
                app.HelperLineLamp.Enable = 1;
            else 
                app.HelperLineLamp.Enable = 0;            
            end
            
        end

        % Selection changed function: ThetaLabelStepValueButtonGroup
        function ThetaLabelStepValueButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThetaLabelStepValueButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: YMinimumSpinner
        function YMinimumSpinnerValueChanged(app, event)
            value = app.YMinimumSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: dBScaleMinimumSpinner
        function dBScaleMinimumSpinnerValueChanged(app, event)
            value = app.dBScaleMinimumSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: dBScaleMaximumSpinner
        function dBScaleMaximumSpinnerValueChanged(app, event)
            value = app.dBScaleMaximumSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Selection changed function: PlotStyleButtonGroup
        function PlotStyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotStyleButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            if app.dBScaleButton.Value
                app.RadialLabelValuesDecreasingOrderEditField.Value = '[0, -10, -20, -30, -40]';
                
            end
            if app.LinearScaleButton.Value
                app.RadialLabelValuesDecreasingOrderEditField.Value = '[1, 0.8, 0.6, 0.4, 0.2]';
                
            end
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Selection changed function: ScaleFactorButtonGroup
        function ScaleFactorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScaleFactorButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: PlotFunctionButton
        function PlotFunctionButtonPushed(app, event)
            
            GeneratePlotTemplateButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
            
            plots = [];
            if app.Function1Button.Value
                sumTot = [];
                lenTot = [];
                
                sweepStep = 0.5;
                theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                d = abs(eval(char(app.Function1EditField.Value)))
                
                symmetryTest = [];
                offsetVal = 180/sweepStep;
                if app.MaxTheta == 180
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                fs = char(app.Function1EditField.Value);
                fs(fs=='.')='';
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            if app.Function2Button.Value
                sumTot = [];
                lenTot = [];
                
                sweepStep = 0.5;
                theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                d = abs(eval(char(app.Function2EditField.Value)))
                
                symmetryTest = [];
                offsetVal = 180/sweepStep;
                if app.MaxTheta == 180
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                fs = char(app.Function2EditField.Value);
                fs(fs=='.')='';
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            if app.Function3Button.Value
                sumTot = [];
                lenTot = [];
                
                sweepStep = 0.5;
                theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                d = abs(eval(char(app.Function3EditField.Value)))
                
                symmetryTest = [];
                offsetVal = 180/sweepStep;
                if app.MaxTheta == 180
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                fs = char(app.Function3EditField.Value);
                fs(fs=='.')='';
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            if app.Function4Button.Value
                sumTot = [];
                lenTot = [];
                
                sweepStep = 0.5;
                theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                d = abs(eval(char(app.Function4EditField.Value)))
                
                symmetryTest = [];
                offsetVal = 180/sweepStep;
                if app.MaxTheta == 180
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                fs = char(app.Function4EditField.Value);
                fs(fs=='.')='';
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            if app.Function5Button.Value
                sumTot = [];
                lenTot = [];
                
                sweepStep = 0.5;
                theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                d = abs(eval(char(app.Function5EditField.Value)))
                
                symmetryTest = [];
                offsetVal = 180/sweepStep;
                if app.MaxTheta == 180
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                fs = char(app.Function5EditField.Value);
                fs(fs=='.')='';
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            if app.Function6Button.Value
                sumTot = [];
                lenTot = [];
                
                sweepStep = 0.5;
                theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                d = abs(eval(char(app.Function6EditField.Value)))
                
                symmetryTest = [];
                offsetVal = 180/sweepStep;
                if app.MaxTheta == 180
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                fs = char(app.Function6EditField.Value);
                fs(fs=='.')='';
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            if app.plotImportedData
                
                theta = deg2rad((app.UITable.Data{:,1}));
                d = (app.UITable.Data{:,2});                
                    
                if app.dBScaleButton.Value
                    d_dB = app.dB_ScaleFactor.*log10(d);
                    d_dB(d_dB < app.dB_Min) = app.dB_Min;
                    d_dB(d_dB > app.dB_Max) = app.dB_Max;
                    d_LinRepresentation = dB2LinScaling4Plots(d_dB);
                    [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                else
                    [xDir, yDir] = pol2cart(theta+pi/2, d);
                end                
                
                fs = 'Imported Function';
                
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            
            app.UIAxes.Toolbar.Visible = 'on';
            app.PlotFunctionLamp.Enable = 1;
        end

        % Button pushed function: SelectExcelFiletoImportButton
        function SelectExcelFiletoImportButtonPushed(app, event)
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            [excelFile, excelPath] = uigetfile('*.xlsx');
            if isequal(excelFile, 0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(excelPath,excelFile)])
                app.excelImportedLamp.Enable = 1;
                app.csvImportedLamp.Enable = 0;
                app.matImportedLamp.Enable = 0;
                
                t = readtable(fullfile(excelPath, excelFile));
                app.UITable.Data = t;
                
            end
        end

        % Button pushed function: SelectCSVFiletoImportButton
        function SelectCSVFiletoImportButtonPushed(app, event)
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            [csvFile, csvPath] = uigetfile('*.csv');
            if isequal(csvFile, 0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(csvPath,csvFile)])
                app.excelImportedLamp.Enable = 0;
                app.csvImportedLamp.Enable = 1;
                app.matImportedLamp.Enable = 0;
                
                t = readtable(fullfile(csvPath, csvFile));
                app.UITable.Data = t;
            end
        end

        % Button down function: ImportDataTab
        function ImportDataTabButtonDown(app, event)
            if app.importTemplateRead == 0
                t = readtable('importTemplate.xlsx')
                vars = {'theta', 'linear directivity'}
                app.UITable.Data = t;
                app.importTemplateRead = 1;
                app.excelImportedLamp.Enable = 0;
                app.csvImportedLamp.Enable = 0;
                app.matImportedLamp.Enable = 0;
            end
        end

        % Button pushed function: SelectmatFiletoImportButton
        function SelectmatFiletoImportButtonPushed(app, event)
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            [matFile, matPath] = uigetfile('*.mat');
            if isequal(matFile, 0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(matPath,matFile)])
                app.excelImportedLamp.Enable = 0;
                app.csvImportedLamp.Enable = 0;
                app.matImportedLamp.Enable = 1;
                
                dataLoad = load(fullfile(matPath, matFile));
                theta = dataLoad.theta;
                linearDirectivity = dataLoad.linearDirectivity;
                matFileDataArray = [theta' linearDirectivity'];
                t = array2table(matFileDataArray);
                app.UITable.Data = t;
                
            end
        end

        % Value changed function: PlotImportedDataSwitch
        function PlotImportedDataSwitchValueChanged(app, event)
            value = app.PlotImportedDataSwitch.Value;
            app.PlotFunctionLamp.Enable = 0;
            if strcmp(value, 'Off')
                app.plotImportedData = 0;
            elseif strcmp(value, 'On')
                app.plotImportedData = 1;
            end
            %app.plotImportedData = value;
        end

        % Value changed function: Function1Button
        function Function1ButtonValueChanged(app, event)
            value = app.Function1Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: Function3Button
        function Function3ButtonValueChanged(app, event)
            value = app.Function3Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: Function2Button
        function Function2ButtonValueChanged(app, event)
            value = app.Function2Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: Function4Button
        function Function4ButtonValueChanged(app, event)
            value = app.Function4Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: Full360Button
        function Full360ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 180;
            app.ThetaStep30Button.Value = 1;
            
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[180, 180, 180, 180, 180]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[180, 180, 180, 180, 180]';
            end
            
            app.YMinimumSpinner.Value = 1;
            app.dBScaleMaximumSpinner.Value = 0;
            app.dBScaleMinimumSpinner.Value = -40;


            app.Full360Button.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
            
        end

        % Button pushed function: Full180Button
        function Full180ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 90;
            app.ThetaStep15Button.Value = 1;
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[90, 90, 90, 90, 90]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[90, 90, 90, 90, 90]';
            end


            app.YMinimumSpinner.Value = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Tiered180Button
        function Tiered180ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 90;
            app.ThetaStep15Button.Value = 1;
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 60, 75, 90, 90]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[30, 45, 60, 75, 90]';
            end
            
            
            app.YMinimumSpinner.Value = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Tiered1802Button
        function Tiered1802ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 90;
            app.ThetaStep15Button.Value = 1;
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 60, 90, 90, 90]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 45, 60, 90, 90]';
            end
            
            
            app.YMinimumSpinner.Value = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.65,0.65,0.65];
        end

        % Button pushed function: Tiered360Button_2
        function Tiered360Button_2Pushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 180;
            app.ThetaStep30Button.Value = 1;
            
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[90, 120, 150, 180, 180]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[60, 90, 120, 150, 180]';
            end
            
            app.YMinimumSpinner.Value = 1;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Tiered3602Button
        function Tiered3602ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 180;
            app.ThetaStep15Button.Value = 1;
            
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[75, 90, 150, 180, 180]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 75, 90, 150, 180]';
            end
            
            
            app.YMinimumSpinner.Value = 1;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.65,0.65,0.65];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: Function5EditField
        function Function5EditFieldValueChanged(app, event)
            value = app.Function5EditField.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: Function6EditField
        function Function6EditFieldValueChanged(app, event)
            value = app.Function6EditField.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: DiscardRedundantMirrorsButton
        function DiscardRedundantMirrorsButtonValueChanged(app, event)
            value = app.DiscardRedundantMirrorsButton.Value;
            app.PlotFunctionLamp.Enable = 0;
            if value
                app.discardRedundant = 1;
                app.DiscardRedundantLamp.Enable = 1;
            else
                app.discardRedundant = 0;
                app.DiscardRedundantLamp.Enable = 0;
            end
        end

        % Button pushed function: 
        % OpenExternalFigureWindowforSavingCopyingImageButton
        function OpenExternalFigureWindowforSavingCopyingImageButtonPushed(app, event)
            ax = axes();
            ax.XAxis.Visible = 'off';
            ax.YAxis.Visible = 'off';
            copyUIAxes(app.UIAxes, ax);                       
            export_fig ax -clipboard 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmartPolarPlotsUIFigure = uifigure('Visible', 'off');
            app.SmartPolarPlotsUIFigure.Name = 'Smart Polar Plots';
            app.UIAxes = uiaxes(app.SmartPolarPlotsUIFigure);
            title(app.UIAxes, 'Directivity')
            app.UIAxes.XLim = [-1.1 1.1];
            app.UIAxes.YLim = [-0.6 1.1];
            app.TabGroup = uitabgroup(app.SmartPolarPlotsUIFigure);
            app.PlotSetupTab = uitab(app.TabGroup);
            app.PlotSetupTab.Title = 'Plot Setup';
            app.PlotStyleButtonGroup = uibuttongroup(app.PlotSetupTab);
            app.PlotStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotStyleButtonGroupSelectionChanged, true);
            app.PlotStyleButtonGroup.Title = 'Plot Style';
            app.dBScaleButton = uiradiobutton(app.PlotStyleButtonGroup);
            app.dBScaleButton.Text = 'dB Scale';
            app.LinearScaleButton = uiradiobutton(app.PlotStyleButtonGroup);
            app.LinearScaleButton.Text = 'Linear Scale';
            app.LinearScaleButton.Value = true;
            app.MaxThetaSpinnerLabel = uilabel(app.PlotSetupTab);
            app.MaxThetaSpinnerLabel.Text = 'Max Theta';
            app.MaxThetaSpinner = uispinner(app.PlotSetupTab);
            app.MaxThetaSpinner.Step = 5;
            app.MaxThetaSpinner.Limits = [0 180];
            app.MaxThetaSpinner.RoundFractionalValues = 'on';
            app.MaxThetaSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxThetaSpinnerValueChanged, true);
            app.MaxThetaSpinner.Value = 180;
            app.ThetaLabelStepValueButtonGroup = uibuttongroup(app.PlotSetupTab);
            app.ThetaLabelStepValueButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThetaLabelStepValueButtonGroupSelectionChanged, true);
            app.ThetaLabelStepValueButtonGroup.Title = 'Theta Label Step Value';
            app.ThetaStep5Button = uiradiobutton(app.ThetaLabelStepValueButtonGroup);
            app.ThetaStep5Button.Text = '5';
            app.ThetaStep10Button = uiradiobutton(app.ThetaLabelStepValueButtonGroup);
            app.ThetaStep10Button.Text = '10';
            app.ThetaStep15Button = uiradiobutton(app.ThetaLabelStepValueButtonGroup);
            app.ThetaStep15Button.Text = '15';
            app.ThetaStep30Button = uiradiobutton(app.ThetaLabelStepValueButtonGroup);
            app.ThetaStep30Button.Text = '30';
            app.ThetaStep30Button.Value = true;
            app.ThetaStep45Button = uiradiobutton(app.ThetaLabelStepValueButtonGroup);
            app.ThetaStep45Button.Text = '45';
            app.ThetaStep60Button = uiradiobutton(app.ThetaLabelStepValueButtonGroup);
            app.ThetaStep60Button.Text = '60';
            app.ScaleFactorButtonGroup = uibuttongroup(app.PlotSetupTab);
            app.ScaleFactorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScaleFactorButtonGroupSelectionChanged, true);
            app.ScaleFactorButtonGroup.Title = 'Scale Factor';
            app.dB_Scale10Button = uiradiobutton(app.ScaleFactorButtonGroup);
            app.dB_Scale10Button.Text = '10 (Power)';
            app.dB_Scale20Button = uiradiobutton(app.ScaleFactorButtonGroup);
            app.dB_Scale20Button.Text = '20 (Voltage)';
            app.dB_Scale20Button.Value = true;
            app.YMinimumSpinnerLabel = uilabel(app.PlotSetupTab);
            app.YMinimumSpinnerLabel.Text = 'Y Minimum';
            app.YMinimumSpinner = uispinner(app.PlotSetupTab);
            app.YMinimumSpinner.Step = 0.1;
            app.YMinimumSpinner.Limits = [0 1];
            app.YMinimumSpinner.ValueDisplayFormat = '%1.1g';
            app.YMinimumSpinner.ValueChangedFcn = createCallbackFcn(app, @YMinimumSpinnerValueChanged, true);
            app.YMinimumSpinner.Value = 1;
            app.RadialLabelValuesDecreasingOrderEditFieldLabel = uilabel(app.PlotSetupTab);
            app.RadialLabelValuesDecreasingOrderEditFieldLabel.Text = 'Radial Label Values: Decreasing Order';
            app.RadialLabelValuesDecreasingOrderEditField = uieditfield(app.PlotSetupTab, 'text');
            app.RadialLabelValuesDecreasingOrderEditField.ValueChangedFcn = createCallbackFcn(app, @RadialLabelValuesDecreasingOrderEditFieldValueChanged, true);
            app.RadialLabelValuesDecreasingOrderEditField.Placeholder = '[1, 0.8, 0.6, 0.4, 0.2]';
            app.RadialLabelValuesDecreasingOrderEditField.Value = '[1, 0.8, 0.6, 0.4, 0.2]';
            app.RadialLabelValueOneSidedBWEditFieldLabel = uilabel(app.PlotSetupTab);
            app.RadialLabelValueOneSidedBWEditFieldLabel.Text = 'Radial Label Value One Sided BW';
            app.RadialLabelValueOneSidedBWEditField = uieditfield(app.PlotSetupTab, 'text');
            app.RadialLabelValueOneSidedBWEditField.ValueChangedFcn = createCallbackFcn(app, @RadialLabelValueOneSidedBWEditFieldValueChanged, true);
            app.RadialLabelValueOneSidedBWEditField.Placeholder = '[180, 180, 180, 180, 180]';
            app.RadialLabelValueOneSidedBWEditField.Value = '[180, 180, 180, 180, 180]';
            app.RadialLabelLocationButtonGroup = uibuttongroup(app.PlotSetupTab);
            app.RadialLabelLocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadialLabelLocationButtonGroupSelectionChanged, true);
            app.RadialLabelLocationButtonGroup.Title = 'Radial Label Location';
            app.LeftButton = uiradiobutton(app.RadialLabelLocationButtonGroup);
            app.LeftButton.Text = 'Left';
            app.CenterButton = uiradiobutton(app.RadialLabelLocationButtonGroup);
            app.CenterButton.Text = 'Center';
            app.CenterButton.Value = true;
            app.RightButton = uiradiobutton(app.RadialLabelLocationButtonGroup);
            app.RightButton.Text = 'Right';
            app.dBScaleMinimumSpinnerLabel = uilabel(app.PlotSetupTab);
            app.dBScaleMinimumSpinnerLabel.Text = 'dB Scale Minimum';
            app.dBScaleMinimumSpinner = uispinner(app.PlotSetupTab);
            app.dBScaleMinimumSpinner.Step = 5;
            app.dBScaleMinimumSpinner.RoundFractionalValues = 'on';
            app.dBScaleMinimumSpinner.ValueChangedFcn = createCallbackFcn(app, @dBScaleMinimumSpinnerValueChanged, true);
            app.dBScaleMinimumSpinner.Value = -40;
            app.dBScaleMaximumSpinnerLabel = uilabel(app.PlotSetupTab);
            app.dBScaleMaximumSpinnerLabel.Text = 'dB Scale Maximum';
            app.dBScaleMaximumSpinner = uispinner(app.PlotSetupTab);
            app.dBScaleMaximumSpinner.Step = 5;
            app.dBScaleMaximumSpinner.RoundFractionalValues = 'on';
            app.dBScaleMaximumSpinner.ValueChangedFcn = createCallbackFcn(app, @dBScaleMaximumSpinnerValueChanged, true);
            app.HalfPowerHelperLineButton = uibutton(app.PlotSetupTab, 'state');
            app.HalfPowerHelperLineButton.ValueChangedFcn = createCallbackFcn(app, @HalfPowerHelperLineButtonValueChanged, true);
            app.HalfPowerHelperLineButton.Text = 'Half Power Helper Line';
            app.Hyperlink = uihyperlink(app.PlotSetupTab);
            app.Hyperlink.URL = 'https://github.com/mbisbano1/MatlabSmartPolarPlots/blob/main/README.md';
            app.Hyperlink.Text = 'Help';
            app.HelperLineLamp = uilamp(app.PlotSetupTab);
            app.HelperLineLamp.Enable = 'off';
            app.DefaultSettingsModesPanel = uipanel(app.PlotSetupTab);
            app.DefaultSettingsModesPanel.Title = 'Default Settings Modes';
            app.Full360Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Full360Button.ButtonPushedFcn = createCallbackFcn(app, @Full360ButtonPushed, true);
            app.Full360Button.Text = 'Full 360°';
            app.Full180Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Full180Button.ButtonPushedFcn = createCallbackFcn(app, @Full180ButtonPushed, true);
            app.Full180Button.Text = 'Full 180°';
            app.Tiered3602Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered3602Button.ButtonPushedFcn = createCallbackFcn(app, @Tiered3602ButtonPushed, true);
            app.Tiered3602Button.Text = 'Tiered 360° #2';
            app.Tiered180Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered180Button.ButtonPushedFcn = createCallbackFcn(app, @Tiered180ButtonPushed, true);
            app.Tiered180Button.Text = 'Tiered 180°';
            app.Tiered1802Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered1802Button.ButtonPushedFcn = createCallbackFcn(app, @Tiered1802ButtonPushed, true);
            app.Tiered1802Button.Text = 'Tiered 180° #2';
            app.Tiered360Button_2 = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered360Button_2.ButtonPushedFcn = createCallbackFcn(app, @Tiered360Button_2Pushed, true);
            app.Tiered360Button_2.Text = 'Tiered 360°';
            app.DiscardRedundantMirrorsButton = uibutton(app.PlotSetupTab, 'state');
            app.DiscardRedundantMirrorsButton.ValueChangedFcn = createCallbackFcn(app, @DiscardRedundantMirrorsButtonValueChanged, true);
            app.DiscardRedundantMirrorsButton.Text = 'Discard Redundant Mirrors';
            app.DiscardRedundantLamp = uilamp(app.PlotSetupTab);
            app.DiscardRedundantLamp.Enable = 'off';
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Title = 'Import Data';
            app.ImportDataTab.ButtonDownFcn = createCallbackFcn(app, @ImportDataTabButtonDown, true);
            app.SelectExcelFiletoImportButton = uibutton(app.ImportDataTab, 'push');
            app.SelectExcelFiletoImportButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelFiletoImportButtonPushed, true);
            app.SelectExcelFiletoImportButton.Text = 'Select Excel File to Import';
            app.SelectCSVFiletoImportButton = uibutton(app.ImportDataTab, 'push');
            app.SelectCSVFiletoImportButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCSVFiletoImportButtonPushed, true);
            app.SelectCSVFiletoImportButton.Text = 'Select CSV File to Import';
            app.UITable = uitable(app.ImportDataTab);
            app.UITable.ColumnName = {'theta'; 'linearDirectivity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SelectmatFiletoImportButton = uibutton(app.ImportDataTab, 'push');
            app.SelectmatFiletoImportButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmatFiletoImportButtonPushed, true);
            app.SelectmatFiletoImportButton.Text = 'Select .mat File to Import';
            app.matImportedLamp = uilamp(app.ImportDataTab);
            app.matImportedLamp.Enable = 'off';
            app.csvImportedLamp = uilamp(app.ImportDataTab);
            app.csvImportedLamp.Enable = 'off';
            app.excelImportedLamp = uilamp(app.ImportDataTab);
            app.excelImportedLamp.Enable = 'off';
            app.PlotImportedDataSwitchLabel = uilabel(app.ImportDataTab);
            app.PlotImportedDataSwitchLabel.Text = 'Plot Imported Data?';
            app.PlotImportedDataSwitch = uiswitch(app.ImportDataTab, 'slider');
            app.PlotImportedDataSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotImportedDataSwitchValueChanged, true);
            app.Hyperlink_2 = uihyperlink(app.ImportDataTab);
            app.Hyperlink_2.URL = 'https://github.com/mbisbano1/MatlabSmartPolarPlots/blob/main/README.md';
            app.Hyperlink_2.Text = 'Help';
            app.Function1EditField = uieditfield(app.SmartPolarPlotsUIFigure, 'text');
            app.Function1EditField.Placeholder = '(1+cos(theta))/2';
            app.Function1EditField.Value = '(1+cos(theta))/2';
            app.Function2EditField = uieditfield(app.SmartPolarPlotsUIFigure, 'text');
            app.Function2EditField.Placeholder = '(1+cos(theta))/2';
            app.Function2EditField.Value = 'cos(theta).^2';
            app.Function3EditField = uieditfield(app.SmartPolarPlotsUIFigure, 'text');
            app.Function3EditField.Placeholder = '(1+cos(theta))/2';
            app.Function3EditField.Value = 'cos(theta).^3';
            app.Function4EditField = uieditfield(app.SmartPolarPlotsUIFigure, 'text');
            app.Function4EditField.Placeholder = '(1+cos(theta))/2';
            app.Function4EditField.Value = 'cos(theta).^4';
            app.Function1Button = uibutton(app.SmartPolarPlotsUIFigure, 'state');
            app.Function1Button.ValueChangedFcn = createCallbackFcn(app, @Function1ButtonValueChanged, true);
            app.Function1Button.Text = 'Function 1';
            app.Function1Button.Value = true;
            app.Function3Button = uibutton(app.SmartPolarPlotsUIFigure, 'state');
            app.Function3Button.ValueChangedFcn = createCallbackFcn(app, @Function3ButtonValueChanged, true);
            app.Function3Button.Text = 'Function 3';
            app.Function2Button = uibutton(app.SmartPolarPlotsUIFigure, 'state');
            app.Function2Button.ValueChangedFcn = createCallbackFcn(app, @Function2ButtonValueChanged, true);
            app.Function2Button.Text = 'Function 2';
            app.Function4Button = uibutton(app.SmartPolarPlotsUIFigure, 'state');
            app.Function4Button.ValueChangedFcn = createCallbackFcn(app, @Function4ButtonValueChanged, true);
            app.Function4Button.Text = 'Function 4';
            app.GeneratePlotTemplateButton = uibutton(app.SmartPolarPlotsUIFigure, 'push');
            app.GeneratePlotTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotTemplateButtonPushed, true);
            app.GeneratePlotTemplateButton.Text = 'Generate Plot Template';
            app.PlotFunctionButton = uibutton(app.SmartPolarPlotsUIFigure, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            app.PlotTemplateLamp = uilamp(app.SmartPolarPlotsUIFigure);
            app.PlotTemplateLamp.Enable = 'off';
            app.PlotFunctionLamp = uilamp(app.SmartPolarPlotsUIFigure);
            app.PlotFunctionLamp.Enable = 'off';
            app.Function5EditField = uieditfield(app.SmartPolarPlotsUIFigure, 'text');
            app.Function5EditField.ValueChangedFcn = createCallbackFcn(app, @Function5EditFieldValueChanged, true);
            app.Function5EditField.Placeholder = '(1+cos(theta))/2';
            app.Function5EditField.Value = 'cos(theta).^5';
            app.Function6EditField = uieditfield(app.SmartPolarPlotsUIFigure, 'text');
            app.Function6EditField.ValueChangedFcn = createCallbackFcn(app, @Function6EditFieldValueChanged, true);
            app.Function6EditField.Placeholder = '(1+cos(theta))/2';
            app.Function6EditField.Value = 'cos(theta).^6';
            app.Function5Button = uibutton(app.SmartPolarPlotsUIFigure, 'state');
            app.Function5Button.Text = 'Function 5';
            app.Function6Button = uibutton(app.SmartPolarPlotsUIFigure, 'state');
            app.Function6Button.Text = 'Function 6';
            app.OpenExternalFigureWindowforSavingCopyingImageButton = uibutton(app.SmartPolarPlotsUIFigure, 'push');
            app.OpenExternalFigureWindowforSavingCopyingImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExternalFigureWindowforSavingCopyingImageButtonPushed, true);
            app.OpenExternalFigureWindowforSavingCopyingImageButton.Text = 'Open External Figure Window for Saving/Copying Image';
            % Show the figure after all components are created
            app.SmartPolarPlotsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DirectionalFactorPolarPlots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmartPolarPlotsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmartPolarPlotsUIFigure)
        end
    end
end