classdef DirectionalFactorPolarPlotsV2_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmartPolarPlotsUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LineColorDropDown_6             matlab.ui.control.DropDown
        LineColorDropDown_5             matlab.ui.control.DropDown
        LineColorDropDown_4             matlab.ui.control.DropDown
        LineColorDropDown_3             matlab.ui.control.DropDown
        LineColorDropDown_2             matlab.ui.control.DropDown
        LineColorDropDown_1             matlab.ui.control.DropDown
        LineColorDropDownLabel          matlab.ui.control.Label
        LineTypeDropDown_6              matlab.ui.control.DropDown
        LineTypeDropDown_5              matlab.ui.control.DropDown
        LineTypeDropDown_4              matlab.ui.control.DropDown
        LineTypeDropDown_3              matlab.ui.control.DropDown
        LineTypeDropDown_2              matlab.ui.control.DropDown
        LineTypeDropDown_1              matlab.ui.control.DropDown
        LineTypeDropDownLabel           matlab.ui.control.Label
        DirectivitiesEditField_6        matlab.ui.control.NumericEditField
        DirectivitiesEditField_5        matlab.ui.control.NumericEditField
        DirectivitiesEditField_4        matlab.ui.control.NumericEditField
        DirectivitiesEditField_3        matlab.ui.control.NumericEditField
        DirectivitiesEditField_2        matlab.ui.control.NumericEditField
        DirectivitiesEditField_1        matlab.ui.control.NumericEditField
        DirectivitiesEditFieldLabel     matlab.ui.control.Label
        EquationLabel                   matlab.ui.control.Label
        LegendEntryEditField_5          matlab.ui.control.EditField
        LegendEntryEditField_6          matlab.ui.control.EditField
        LegendEntryEditField_4          matlab.ui.control.EditField
        LegendEntryEditField_3          matlab.ui.control.EditField
        LegendEntryEditField_2          matlab.ui.control.EditField
        LegendEntryEditField_1          matlab.ui.control.EditField
        LegendEntryEditFieldLabel       matlab.ui.control.Label
        OpenExternalFigureWindowforSavingCopyingImageButton  matlab.ui.control.Button
        Function6Button                 matlab.ui.control.StateButton
        Function5Button                 matlab.ui.control.StateButton
        Function6EditField              matlab.ui.control.EditField
        Function5EditField              matlab.ui.control.EditField
        PlotFunctionLamp                matlab.ui.control.Lamp
        PlotTemplateLamp                matlab.ui.control.Lamp
        PlotFunctionButton              matlab.ui.control.Button
        GeneratePlotTemplateButton      matlab.ui.control.Button
        Function4Button                 matlab.ui.control.StateButton
        Function2Button                 matlab.ui.control.StateButton
        Function3Button                 matlab.ui.control.StateButton
        Function1Button                 matlab.ui.control.StateButton
        Function4EditField              matlab.ui.control.EditField
        Function3EditField              matlab.ui.control.EditField
        Function2EditField              matlab.ui.control.EditField
        Function1EditField              matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        PlotSetupTab                    matlab.ui.container.Tab
        LegendSettingsPanel             matlab.ui.container.Panel
        LegendOnButton                  matlab.ui.control.StateButton
        LegendLocationDropDown          matlab.ui.control.DropDown
        LegendLocationDropDownLabel     matlab.ui.control.Label
        CustomSettingsFilesPanel        matlab.ui.container.Panel
        LoadSettingsButton              matlab.ui.control.Button
        ExportSettingsButton            matlab.ui.control.Button
        ThetaLabelStepSizeSpinner       matlab.ui.control.Spinner
        ThetaLabelStepSizeSpinnerLabel  matlab.ui.control.Label
        DiscardRedundantLamp            matlab.ui.control.Lamp
        DiscardRedundantMirrorsButton   matlab.ui.control.StateButton
        DefaultSettingsModesPanel       matlab.ui.container.Panel
        Tiered360Button_2               matlab.ui.control.Button
        Tiered1802Button                matlab.ui.control.Button
        Tiered180Button                 matlab.ui.control.Button
        Tiered3602Button                matlab.ui.control.Button
        Full180Button                   matlab.ui.control.Button
        Full360Button                   matlab.ui.control.Button
        HelperLineLamp                  matlab.ui.control.Lamp
        Hyperlink                       matlab.ui.control.Hyperlink
        HalfPowerHelperLineButton       matlab.ui.control.StateButton
        dBScaleMaximumSpinner           matlab.ui.control.Spinner
        dBScaleMaximumSpinnerLabel      matlab.ui.control.Label
        dBScaleMinimumSpinner           matlab.ui.control.Spinner
        dBScaleMinimumSpinnerLabel      matlab.ui.control.Label
        RadialLabelLocationButtonGroup  matlab.ui.container.ButtonGroup
        RightButton                     matlab.ui.control.RadioButton
        CenterButton                    matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        RadialLabelValueOneSidedBWEditField  matlab.ui.control.EditField
        RadialLabelValueOneSidedBWEditFieldLabel  matlab.ui.control.Label
        RadialLabelValuesDecreasingOrderEditField  matlab.ui.control.EditField
        RadialLabelValuesDecreasingOrderEditFieldLabel  matlab.ui.control.Label
        YMinimumSpinner                 matlab.ui.control.Spinner
        YMinimumSpinnerLabel            matlab.ui.control.Label
        ScaleFactorButtonGroup          matlab.ui.container.ButtonGroup
        dB_Scale20Button                matlab.ui.control.RadioButton
        dB_Scale10Button                matlab.ui.control.RadioButton
        MaxThetaSpinner                 matlab.ui.control.Spinner
        MaxThetaSpinnerLabel            matlab.ui.control.Label
        PlotStyleButtonGroup            matlab.ui.container.ButtonGroup
        LinearScaleButton               matlab.ui.control.RadioButton
        dBScaleButton                   matlab.ui.control.RadioButton
        PredefinedPatternsTab           matlab.ui.container.Tab
        CosinetoPowerPanel              matlab.ui.container.Panel
        HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_8  matlab.ui.control.Label
        theta_0degreesEditField_3       matlab.ui.control.NumericEditField
        theta_0degreesEditFieldLabel_3  matlab.ui.control.Label
        HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_7  matlab.ui.control.Label
        AddButton_5                     matlab.ui.control.Button
        FunctiontoAddtoSpinner_5        matlab.ui.control.Spinner
        FunctiontoAddtoSpinner_5Label   matlab.ui.control.Label
        CardioidSourcePanel             matlab.ui.container.Panel
        HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_6  matlab.ui.control.Label
        theta_0degreesEditField_2       matlab.ui.control.NumericEditField
        theta_0degreesEditFieldLabel_2  matlab.ui.control.Label
        HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_5  matlab.ui.control.Label
        AddButton_4                     matlab.ui.control.Button
        FunctiontoAddtoSpinner_4        matlab.ui.control.Spinner
        FunctiontoAddtoSpinner_4Label_2  matlab.ui.control.Label
        Hyperlink_3                     matlab.ui.control.Hyperlink
        ContinuousLineArraySourcesPanel  matlab.ui.container.Panel
        kdEditField                     matlab.ui.control.EditField
        kdLabel_2                       matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NLabel                          matlab.ui.control.Label
        HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_2  matlab.ui.control.Label
        HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_3  matlab.ui.control.Label
        AddButton_3                     matlab.ui.control.Button
        FunctiontoAddtoSpinner_3        matlab.ui.control.Spinner
        FunctiontoAddtoSpinner_3Label   matlab.ui.control.Label
        theta_0degreesEditField         matlab.ui.control.NumericEditField
        theta_0degreesEditFieldLabel    matlab.ui.control.Label
        PlaneCircularPistonSourcesPanel  matlab.ui.container.Panel
        kaEditField                     matlab.ui.control.EditField
        kaEditFieldLabel                matlab.ui.control.Label
        Hthetaleftfrac2J_1vvrighttextwherevkasinthetaLabel  matlab.ui.control.Label
        AddButton_2                     matlab.ui.control.Button
        FunctiontoAddtoSpinner_2        matlab.ui.control.Spinner
        FunctiontoAddtoSpinner_2Label   matlab.ui.control.Label
        ContinuousLineSourcesPanel      matlab.ui.container.Panel
        kLEditField                     matlab.ui.control.EditField
        kLEditField_2Label              matlab.ui.control.Label
        HthetaleftfracsinvvrighttextwherevkLsinthetaLabel  matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        FunctiontoAddtoSpinner          matlab.ui.control.Spinner
        FunctiontoAddtoSpinnerLabel     matlab.ui.control.Label
        ImportDataTab                   matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        PerformOperationsonTableDataPanel  matlab.ui.container.Panel
        RemoveFirstElementButton        matlab.ui.control.Button
        RemoveLastElementButton         matlab.ui.control.Button
        InsertLastElementButton         matlab.ui.control.Button
        InsertFirstElementButton        matlab.ui.control.Button
        TakeAbsoluteValueButton         matlab.ui.control.Button
        ScaleByMaxValueButton           matlab.ui.control.Button
        DirectivityCalculationsWillOnlyWorkwithLabel  matlab.ui.control.Label
        SelectImportLoadSaveSlotButtonGroup  matlab.ui.container.ButtonGroup
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        Import6Button                   matlab.ui.control.RadioButton
        Import5Button                   matlab.ui.control.RadioButton
        Import4Button                   matlab.ui.control.RadioButton
        Import3Button                   matlab.ui.control.RadioButton
        Import2Button                   matlab.ui.control.RadioButton
        Import1Button                   matlab.ui.control.RadioButton
        ImportFunctiontoAddtoSpinner    matlab.ui.control.Spinner
        FunctiontoDisplayonLabel        matlab.ui.control.Label
        ImportAddButton                 matlab.ui.control.Button
        Hyperlink_2                     matlab.ui.control.Hyperlink
        PlotDataFromTableSwitch         matlab.ui.control.Switch
        PlotDataFromTableSwitchLabel    matlab.ui.control.Label
        excelImportedLamp               matlab.ui.control.Lamp
        csvImportedLamp                 matlab.ui.control.Lamp
        matImportedLamp                 matlab.ui.control.Lamp
        SelectmatFiletoImportButton     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SelectCSVFiletoImportButton     matlab.ui.control.Button
        SelectExcelFiletoImportButton   matlab.ui.control.Button
        HelpandInstructionsTab          matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Tab2                            matlab.ui.container.Tab
        FunctionDockPanel               matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        Hyperlink_4                     matlab.ui.control.Hyperlink
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dB_Max 
        dB_Min
        MaxTheta
        dB_ScaleFactor
        importTemplateRead = 0 
        plotImportedData 
        discardRedundant 
        Import1
        Import2
        Import3
        Import4
        Import5
        Import6
        Import1d
        Import2d
        Import3d
        Import4d
        Import5d
        Import6d
        Import1thetaDeg
        Import2thetaDeg
        Import3thetaDeg
        Import4thetaDeg
        Import5thetaDeg
        Import6thetaDeg
        Import1dthetaDeg
        Import2dthetaDeg
        Import3dthetaDeg
        Import4dthetaDeg
        Import5dthetaDeg
        Import6dthetaDeg
        Import1MaxThetaDeg
        Import2MaxThetaDeg
        Import3MaxThetaDeg
        Import4MaxThetaDeg
        Import5MaxThetaDeg
        Import6MaxThetaDeg
        Import1MinThetaDeg
        Import2MinThetaDeg
        Import3MinThetaDeg
        Import4MinThetaDeg
        Import5MinThetaDeg
        Import6MinThetaDeg
        Import1FunctionName
        Import2FunctionName
        Import3FunctionName
        Import4FunctionName
        Import5FunctionName
        Import6FunctionName
        Import1LegendName
        Import2LegendName
        Import3LegendName
        Import4LegendName
        Import5LegendName
        Import6LegendName
        CurrentImport % Description
        Function1thetaDeg
        Function2thetaDeg
        Function3thetaDeg
        Function4thetaDeg
        Function5thetaDeg
        Function6thetaDeg
        Function1d
        Function2d
        Function3d
        Function4d
        Function5d
        Function6d
        Function1dthetaDeg
        Function2dthetaDeg
        Function3dthetaDeg
        Function4dthetaDeg
        Function5dthetaDeg
        Function6dthetaDeg
        Function1MinTheta
        Function2MinTheta
        Function3MinTheta
        Function4MinTheta
        Function5MinTheta
        Function6MinTheta
        Function1MaxTheta
        Function2MaxTheta
        Function3MaxTheta
        Function4MaxTheta
        Function5MaxTheta
        Function6MaxTheta
        Function1Imported
        Function2Imported
        Function3Imported
        Function4Imported
        Function5Imported
        Function6Imported
        Import1table
        Import2table
        Import3table
        Import4table
        Import5table
        Import6table


    end
    
    methods (Access = private)
        
        function lin_val = dB2LinScaling4Plots(~, dB_val, dB_min, dB_max, lin_min, lin_max)
            
            if nargin < 6
                lin_max = 1;
            end
            if nargin < 5
                lin_min = 0;
            end
            if nargin < 4
                dB_max = 0;
            end
            if nargin < 3
                dB_min = -40;
            end

            scale_factor_0to1 = abs(dB_max-dB_min);
            val_0to1 = (dB_val - dB_min)/scale_factor_0to1;
            scale_factor_lin_output = abs(lin_max-lin_min);

            lin_val = scale_factor_lin_output*val_0to1 + lin_min;
        end
        
        function Directivity = DirectivityCalc(~, H, dtheta, minTheta, maxTheta)
            disp(['dtheta = ', num2str(dtheta)]);
            disp(['minTheta = ', num2str(minTheta)]);
            disp(['maxTheta = ', num2str(maxTheta)]);
            %clear theta thetaDeg frow0deg frow90deg frowN90deg frow180deg frowN180deg
            clearvars -except H dtheta minTheta maxTheta
            theta = deg2rad(minTheta:dtheta:maxTheta-dtheta);
            thetaDeg = (minTheta:dtheta:maxTheta-dtheta);
            
            if iscolumn(H)
                H = H.';
            end


            Q = nan;
            
            if minTheta == -180 && maxTheta == 180
                disp('-180 to 180 Directivity Calc:')
                [frow0deg, fcol] = find(thetaDeg(:) == 0.00, 1);
                [frow90deg, fcol] = find(thetaDeg(:) == 90, 1);
                [frowN90deg, fcol] = find(thetaDeg(:) == -90, 1);
                [frow180deg, fcol] = find(thetaDeg(:) == 180-dtheta, 1);
                [frowN180deg, fcol] = find(thetaDeg(:) == -180, 1);
                H1 = H(frow0deg:frow90deg);
                t1 = theta(frow0deg:frow90deg);
                H2 = H(frow90deg:frow180deg);
                t2 = theta(frow90deg:frow180deg);
                H3 = H(frowN180deg:frowN90deg);
                t3 = theta(frowN180deg:frowN90deg);
                H4 = H(frowN90deg:frow0deg);
                t4 = theta(frowN90deg:frow0deg);
                disp(['0Idx: ', num2str(frow0deg), ' 90Idx: ', num2str(frow90deg)]);
                disp(['180Idx: ', num2str(frow180deg), ' -180Idx: ', num2str(frowN180deg)]);
                disp(['-90Idx: ', num2str(frowN90deg)]);
                %Q = trapz(t1, H1.^2) + (trapz(t2, H2.^2)) + (trapz(t3, H3.^2)) + (trapz(t4, H4.^2));
                %Q = trapz(t1, sin(t1).*(H1.^2)) + (trapz(t2, sin(t2).*(H2.^2))) + (trapz(t3, sin(t3).*(H3.^2))) + (trapz(t4, sin(t4).*(H4.^2)));
                Q = abs(trapz(t1, sin(t1).*(H1.^2))) + abs(trapz(t2, sin(t2).*(H2.^2))) + abs(trapz(t3, sin(t3).*(H3.^2))) + abs(trapz(t4, sin(t4).*(H4.^2)));
            %elseif minTheta == -90 && maxTheta == 90
                %H = H;
                %theta = theta;
            
            elseif minTheta == -90 && maxTheta == 90
                disp('-90 to 90 Directivity Calc:');
                [frow0deg, fcol] = find(thetaDeg(:) == 0.00, 1);
                [frow90deg, fcol] = find(thetaDeg(:) == 90-dtheta, 1);
                [frowN90deg, fcol] = find(thetaDeg(:) == -90, 1);
                %[frow180deg, fcol] = find(thetaDeg(:) == 180-dtheta, 1);
                %[frowN180deg, fcol] = find(thetaDeg(:) == -180, 1);
                H1 = H(frow0deg:frow90deg);
                t1 = theta(frow0deg:frow90deg);
                %H2 = H(frow90deg:frow180deg);
                %t2 = theta(frow90deg:frow180deg);
                %H3 = H(frowN180deg:frowN90deg);
                %t3 = theta(frowN180deg:frowN90deg);
                H4 = H(frowN90deg:frow0deg);
                t4 = theta(frowN90deg:frow0deg);
                disp(['0Idx: ', num2str(frow0deg), ' 90Idx: ', num2str(frow90deg)]);
                %disp(['180Idx: ', num2str(frow180deg), ' -180Idx: ', num2str(frowN180deg)]);
                disp(['-90Idx: ', num2str(frowN90deg)]);
                %Q = trapz(t1, H1.^2) + (trapz(t2, H2.^2)) + (trapz(t3, H3.^2)) + (trapz(t4, H4.^2));
                %Q = trapz(t1, sin(t1).*(H1.^2)) + (trapz(t2, sin(t2).*(H2.^2))) + (trapz(t3, sin(t3).*(H3.^2))) + (trapz(t4, sin(t4).*(H4.^2)));
                Q = 2*(abs(trapz(t1, sin(t1).*(H1.^2))) + abs(trapz(t4, sin(t4).*(H4.^2))));
            %elseif minTheta == -90 && maxTheta == 90
                %H = H;
                %theta = theta;
                        
            elseif minTheta == 0 && maxTheta == 360      % 0 to 360 for Austin
                disp('0 to 360 Directivity Calc:');
                [frow0deg, fcol] = find(thetaDeg(:) == 0.00, 1);
                [frow90deg, fcol] = find(thetaDeg(:) == 90-dtheta, 1);
                [frow180deg, fcol] = find(thetaDeg(:) == 180-dtheta, 1);
                [frow270deg, fcol] = find(thetaDeg(:) == 270-dtheta, 1);
                [frow360deg, fcol] = find(thetaDeg(:) == 360-dtheta, 1);
                H1 = H(frow0deg:frow90deg);
                t1 = theta(frow0deg:frow90deg);
                H2 = H(frow90deg+1:frow180deg);
                t2 = theta(frow90deg+1:frow180deg);
                H3 = H(frow180deg+1:frow270deg);
                t3 = theta(frow180deg+1:frow270deg);
                %H4 = H(frow270deg+1:end);
                %t4 = theta(frow270deg+1:end);
                H4 = H(frow270deg+1:frow360deg);
                t4 = theta(frow270deg+1:frow360deg);
                
                disp(['0Idx: ', num2str(frow0deg), ' 90Idx: ', num2str(frow90deg)]);
                disp(['180Idx: ', num2str(frow180deg), ' 270Idx: ', num2str(frow270deg)]);
                disp(['360Idx: ', num2str(frow360deg)]);
                
                Q = abs(trapz(t1, sin(t1).*(H1.^2))) + abs(trapz(t2, sin(t2).*(H2.^2))) + abs(trapz(t3, sin(t3).*(H3.^2))) + abs(trapz(t4, sin(t4).*(H4.^2)));
            end
            %theta = -pi/2:deg2rad(dtheta):pi/2;
            %Q = trapz(theta, sin(theta).*H.^2);
            phi = 0:pi/100000:pi/2;
            H2 = ones(1, length(phi));
            Q2 = 2*trapz(phi, (H2.^2));
            if ~isnan(Q)
                Directivity = 4*pi/(Q*Q2);
            else
                Directivity = -1;
            end                                                            
        end
        
        function y = jinc(~, x)
            y = ones(size(x));
            y(x~=0) = 2*besselj(1,x(x~=0))./(x(x~=0));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: PlotStyleButtonGroup
        function PlotStyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotStyleButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            if app.dBScaleButton.Value
                app.RadialLabelValuesDecreasingOrderEditField.Value = '[0, -10, -20, -30, -40]';
                
            end
            if app.LinearScaleButton.Value
                app.RadialLabelValuesDecreasingOrderEditField.Value = '[1, 0.8, 0.6, 0.4, 0.2]';
                
            end
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: MaxThetaSpinner
        function MaxThetaSpinnerValueChanged(app, event)
            value = app.MaxThetaSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Selection changed function: ScaleFactorButtonGroup
        function ScaleFactorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScaleFactorButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: YMinimumSpinner
        function YMinimumSpinnerValueChanged(app, event)
            value = app.YMinimumSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: 
        % RadialLabelValuesDecreasingOrderEditField
        function RadialLabelValuesDecreasingOrderEditFieldValueChanged(app, event)
            value = app.RadialLabelValuesDecreasingOrderEditField.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: 
        % RadialLabelValueOneSidedBWEditField
        function RadialLabelValueOneSidedBWEditFieldValueChanged(app, event)
            value = app.RadialLabelValueOneSidedBWEditField.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Callback function
        function ThetaLabelStepValueButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThetaLabelStepValueButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Selection changed function: RadialLabelLocationButtonGroup
        function RadialLabelLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.RadialLabelLocationButtonGroup.SelectedObject;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: dBScaleMinimumSpinner
        function dBScaleMinimumSpinnerValueChanged(app, event)
            value = app.dBScaleMinimumSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: dBScaleMaximumSpinner
        function dBScaleMaximumSpinnerValueChanged(app, event)
            value = app.dBScaleMaximumSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: HalfPowerHelperLineButton
        function HalfPowerHelperLineButtonValueChanged(app, event)
            value = app.HalfPowerHelperLineButton.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            if value
                app.HelperLineLamp.Enable = 1;
            else 
                app.HelperLineLamp.Enable = 0;            
            end
            
        end

        % Button pushed function: Full360Button
        function Full360ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 180;
            app.ThetaLabelStepSizeSpinner.Value = 30;
            
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[180, 180, 180, 180, 180]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[180, 180, 180, 180, 180]';
            end
            
            app.YMinimumSpinner.Value = 1;
            app.dBScaleMaximumSpinner.Value = 0;
            app.dBScaleMinimumSpinner.Value = -40;


            app.Full360Button.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
            
        end

        % Button pushed function: Full180Button
        function Full180ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 90;
            app.ThetaLabelStepSizeSpinner.Value = 15;
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[90, 90, 90, 90, 90]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[90, 90, 90, 90, 90]';
            end


            app.YMinimumSpinner.Value = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Tiered3602Button
        function Tiered3602ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 180;
            app.ThetaLabelStepSizeSpinner.Value = 15;
            
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[75, 90, 150, 180, 180]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 75, 90, 150, 180]';
            end
            
            
            app.YMinimumSpinner.Value = 1;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.65,0.65,0.65];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Tiered180Button
        function Tiered180ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 90;
            app.ThetaLabelStepSizeSpinner.Value = 15;
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 60, 75, 90, 90]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[30, 45, 60, 75, 90]';
            end
            
            
            app.YMinimumSpinner.Value = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Tiered1802Button
        function Tiered1802ButtonPushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 90;
            app.ThetaLabelStepSizeSpinner.Value = 15;
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 60, 90, 90, 90]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[45, 45, 60, 90, 90]';
            end
            
            
            app.YMinimumSpinner.Value = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.65,0.65,0.65];
        end

        % Button pushed function: Tiered360Button_2
        function Tiered360Button_2Pushed(app, event)
            app.PlotFunctionLamp.Enable = 0;
            app.PlotTemplateLamp.Enable = 0;
            app.MaxThetaSpinner.Value = 180;
            app.ThetaLabelStepSizeSpinner.Value = 30;
            
            if app.dBScaleButton.Value
                app.RadialLabelValueOneSidedBWEditField.Value = '[90, 120, 150, 180, 180]';
            else
                app.RadialLabelValueOneSidedBWEditField.Value = '[60, 90, 120, 150, 180]';
            end
            
            app.YMinimumSpinner.Value = 1;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.65,0.65,0.65];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: DiscardRedundantMirrorsButton
        function DiscardRedundantMirrorsButtonValueChanged(app, event)
            value = app.DiscardRedundantMirrorsButton.Value;
            app.PlotFunctionLamp.Enable = 0;
            if value
                app.discardRedundant = 1;
                app.DiscardRedundantLamp.Enable = 1;
            else
                app.discardRedundant = 0;
                app.DiscardRedundantLamp.Enable = 0;
            end
        end

        % Value changed function: ThetaLabelStepSizeSpinner
        function ThetaLabelStepSizeSpinnerValueChanged(app, event)
            value = app.ThetaLabelStepSizeSpinner.Value;
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            
            if app.RightButton.Value
                RadialLabelLocationValue = 0;
            elseif app.CenterButton.Value
                RadialLabelLocationValue = 1;
            else
                RadialLabelLocationValue = 2;
            end           
            C = cell(26,1);
            C{1,1} = 1*app.dBScaleButton.Value;
            C{2,1} = 1*app.dB_Scale10Button.Value;
            C{3,1} = RadialLabelLocationValue;
            C{4,1} = app.dBScaleMaximumSpinner.Value;
            C{5,1} = app.dBScaleMinimumSpinner.Value;
            C{6,1} = app.YMinimumSpinner.Value;
            C{7,1} = app.ThetaLabelStepSizeSpinner.Value;
            C{8,1} = app.MaxThetaSpinner.Value;
            C{9,1} = app.RadialLabelValuesDecreasingOrderEditField.Value;
            C{10,1}= app.RadialLabelValueOneSidedBWEditField.Value;
            C{11,1}= 1*app.HalfPowerHelperLineButton.Value;
            C{12,1}= 1*app.DiscardRedundantMirrorsButton.Value; 
            % Line Colors
            C{13,1}= app.LineColorDropDown_1.Value;
            C{14,1}= app.LineColorDropDown_2.Value;
            C{15,1}= app.LineColorDropDown_3.Value;
            C{16,1}= app.LineColorDropDown_4.Value;
            C{17,1}= app.LineColorDropDown_5.Value;
            C{18,1}= app.LineColorDropDown_6.Value;
            % Line Styles
            C{19,1} = app.LineTypeDropDown_1.Value;
            C{20,1} = app.LineTypeDropDown_2.Value;
            C{21,1} = app.LineTypeDropDown_3.Value;
            C{22,1} = app.LineTypeDropDown_4.Value;
            C{23,1} = app.LineTypeDropDown_5.Value;
            C{24,1} = app.LineTypeDropDown_6.Value;
            % Legend Settings
            C{25,1} = app.LegendLocationDropDown.Value;
            C{26,1} = app.LegendOnButton.Value;
            
            
            [settingsFileOut, settingsPathOut] = uiputfile('myPolarPlotConfig.xlsx');
            writecell(C,fullfile(settingsPathOut,settingsFileOut));
%             settingsFileID = fopen(fullfile(settingsPathOut, settingsFileOut),"w");            
%             %fprintf(settingsFileID,'%s\r\n',num2str(app.dBScaleButton.Value));
%             %fprintf(settingsFileID,'%s\r\n',num2str(app.dB_Scale10Button.Value));
%             fprintf(settingsFileID,'%s\r\n',num2str(RadialLabelLocationValue));
%             fprintf(settingsFileID,'%s\r\n',num2str(app.dBScaleMaximumSpinner.Value));
%             fprintf(settingsFileID,'%s\r\n',num2str(app.dBScaleMinimumSpinner.Value));
%             fprintf(settingsFileID,'%s\r\n',num2str(app.YMinimumSpinner.Value));
%             fprintf(settingsFileID,'%s\r\n',num2str(app.ThetaLabelStepSizeSpinner.Value));
%             fprintf(settingsFileID,'%s\r\n',num2str(app.MaxThetaSpinner.Value));
%             fprintf(settingsFileID,'%s\r\n',app.RadialLabelValuesDecreasingOrderEditField.Value);
%             fprintf(settingsFileID,'%s\r\n',app.RadialLabelValueOneSidedBWEditField.Value);
%             fprintf(settingsFileID,'%s\r\n',num2str(app.HalfPowerHelperLineButton.Value));
%             fprintf(settingsFileID,'%s\r\n',num2str(app.DiscardRedundantMirrorsButton.Value));            
%             
%             fclose(settingsFileID);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [settingsFileIn, settingsPathIn] = uigetfile('*.xlsx');
            %settingsFileID = fopen(fullfile(settingsPathIn, settingsFileIn),"r");
            T = readcell(fullfile(settingsPathIn,settingsFileIn));
            
            % Plot Style Button Group
            app.dBScaleButton.Value = T{1,:};
            app.LinearScaleButton.Value = ~app.dBScaleButton.Value;
            % Scale Factor Button Group
            app.dB_Scale10Button.Value = T{2,:};
            app.dB_Scale20Button.Value = ~app.dB_Scale10Button.Value;
            % RadialLabelLocation 
            LCR = T{3,:};
            if LCR == 0
                app.LeftButton.Value = 0;
                app.CenterButton.Value = 0;
                app.RightButton.Value = 1;
            elseif LCR == 1
                app.LeftButton.Value = 0;
                app.CenterButton.Value = 1;
                app.RightButton.Value = 0;
            else
                app.LeftButton.Value = 1;
                app.CenterButton.Value = 0;
                app.RightButton.Value = 0;
            end
            % dB Scale Max / Min
            app.dBScaleMaximumSpinner.Value = T{4,:};
            app.dBScaleMinimumSpinner.Value = T{5,:};
            % Y Minimum
            app.YMinimumSpinner.Value = T{6,:};
            % ThetaLabel Step Size and MaxTheta
            app.ThetaLabelStepSizeSpinner.Value = T{7,:};
            app.MaxThetaSpinner.Value = T{8,:};
            % Radial Label Values
            app.RadialLabelValuesDecreasingOrderEditField.Value = char(T{9,:});
            app.RadialLabelValueOneSidedBWEditField.Value = char(T{10,:});
            % Half Power Helper Line and Discard Redundant Mirrors
            app.HalfPowerHelperLineButton.Value = T{11,:};
            app.DiscardRedundantMirrorsButton.Value = T{12,:};
            
            % Line Colors
            app.LineColorDropDown_1.Value = T{13,:};
            app.LineColorDropDown_2.Value = T{14,:};
            app.LineColorDropDown_3.Value = T{15,:};
            app.LineColorDropDown_4.Value = T{16,:};
            app.LineColorDropDown_5.Value = T{17,:};
            app.LineColorDropDown_6.Value = T{18,:};
            % Line Styles
            app.LineTypeDropDown_1.Value = T{19,:};
            app.LineTypeDropDown_2.Value = T{20,:};
            app.LineTypeDropDown_3.Value = T{21,:};
            app.LineTypeDropDown_4.Value = T{22,:};
            app.LineTypeDropDown_5.Value = T{23,:};
            app.LineTypeDropDown_6.Value = T{24,:};
            % Legend Settings
            app.LegendLocationDropDown.Value = T{25,:};
            app.LegendOnButton.Value = T{26,:};


            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            app.Full360Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered360Button_2.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered3602Button.BackgroundColor = [0.96,0.96,0.96];
            app.Full180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered180Button.BackgroundColor = [0.96,0.96,0.96];
            app.Tiered1802Button.BackgroundColor = [0.96,0.96,0.96];
            
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            kL = eval(app.kLEditField.Value);
            dest = app.FunctiontoAddtoSpinner.Value;
            eqString = ['abs(sinc(0.5*', num2str(kL), '.*sin(theta)))'];  
            legendEntryString = ['CLS: kL = ', app.kLEditField.Value];
            switch dest
                case 1
                    app.Function1EditField.Value = eqString;
                    app.LegendEntryEditField_1.Value = legendEntryString;
                case 2
                    app.Function2EditField.Value = eqString;
                    app.LegendEntryEditField_2.Value = legendEntryString;
                case 3
                    app.Function3EditField.Value = eqString;
                    app.LegendEntryEditField_3.Value = legendEntryString;
                case 4
                    app.Function4EditField.Value = eqString;
                    app.LegendEntryEditField_4.Value = legendEntryString;
                case 5
                    app.Function5EditField.Value = eqString;
                    app.LegendEntryEditField_5.Value = legendEntryString;
                case 6
                    app.Function6EditField.Value = eqString;
                    app.LegendEntryEditField_6.Value = legendEntryString;
            end  
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            ka = eval(app.kaEditField.Value);
            dest = app.FunctiontoAddtoSpinner_2.Value;
            eqString = ['abs(app.jinc(',num2str(ka),'.*sin(theta)))'];
            legendEntryString = ['CPS: ka = ', app.kaEditField.Value];                    
            switch dest
                case 1
                    app.Function1EditField.Value = eqString;
                    app.LegendEntryEditField_1.Value = legendEntryString;
                case 2
                    app.Function2EditField.Value = eqString;
                    app.LegendEntryEditField_2.Value = legendEntryString;
                case 3
                    app.Function3EditField.Value = eqString;
                    app.LegendEntryEditField_3.Value = legendEntryString;
                case 4
                    app.Function4EditField.Value = eqString;
                    app.LegendEntryEditField_4.Value = legendEntryString;
                case 5
                    app.Function5EditField.Value = eqString;
                    app.LegendEntryEditField_5.Value = legendEntryString;
                case 6
                    app.Function6EditField.Value = eqString;
                    app.LegendEntryEditField_6.Value = legendEntryString;
            end  
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
            kd = eval(app.kdEditField.Value);
            dest = app.FunctiontoAddtoSpinner_3.Value;
            N = app.NEditField.Value;
            theta0 = app.theta_0degreesEditField.Value;
            numString = ['(sin((',num2str(N),'/2).*',num2str(kd),'.*(sin(theta)-sin(',num2str(deg2rad(theta0)),'))))'];
            denString = ['(',num2str(N),'*(sin((1/2).*',num2str(kd),'.*(sin(theta)-sin(',num2str(deg2rad(theta0)),')))))'];
            eqString = ['abs((',numString,')./(',denString,'))'];  
            legendEntryString = ['CLA: kd=', app.kdEditField.Value, ', N=',num2str(N), ', \theta_0=', num2str(theta0)];                    
            switch dest
                case 1
                    app.Function1EditField.Value = eqString;
                    app.LegendEntryEditField_1.Value = legendEntryString;
                case 2
                    app.Function2EditField.Value = eqString;
                    app.LegendEntryEditField_2.Value = legendEntryString;
                case 3
                    app.Function3EditField.Value = eqString;
                    app.LegendEntryEditField_3.Value = legendEntryString;
                case 4
                    app.Function4EditField.Value = eqString;
                    app.LegendEntryEditField_4.Value = legendEntryString;
                case 5
                    app.Function5EditField.Value = eqString;
                    app.LegendEntryEditField_5.Value = legendEntryString;
                case 6
                    app.Function6EditField.Value = eqString;
                    app.LegendEntryEditField_6.Value = legendEntryString;
            end  
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: AddButton_4
        function AddButton_4Pushed(app, event)
            dest = app.FunctiontoAddtoSpinner_4.Value;
            steerAngDeg = app.theta_0degreesEditField_2.Value;
            %numString = ['(sin((',num2str(N),'/2).*',num2str(kd),'.*(sin(theta)-sin(',num2str(deg2rad(theta0)),'))))'];
            %denString = ['(',num2str(N),'*(sin((1/2).*',num2str(kd),'.*(sin(theta)-sin(',num2str(deg2rad(theta0)),')))))'];
            eqString = ['(1+cos(theta-',num2str(deg2rad(steerAngDeg)),'))./2'];  
            legendEntryString = ['Cardioid: \theta_0 = ', num2str(steerAngDeg), ' degrees'];                    
            switch dest
                case 1
                    app.Function1EditField.Value = eqString;
                    app.LegendEntryEditField_1.Value = legendEntryString;
                case 2
                    app.Function2EditField.Value = eqString;
                    app.LegendEntryEditField_2.Value = legendEntryString;
                case 3
                    app.Function3EditField.Value = eqString;
                    app.LegendEntryEditField_3.Value = legendEntryString;
                case 4
                    app.Function4EditField.Value = eqString;
                    app.LegendEntryEditField_4.Value = legendEntryString;
                case 5
                    app.Function5EditField.Value = eqString;
                    app.LegendEntryEditField_5.Value = legendEntryString;
                case 6
                    app.Function6EditField.Value = eqString;
                    app.LegendEntryEditField_6.Value = legendEntryString;
            end  
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: AddButton_5
        function AddButton_5Pushed(app, event)
            dest = app.FunctiontoAddtoSpinner_5.Value;
            power = app.theta_0degreesEditField_3.Value;
            %steerAngDeg = app.theta_0degreesEditField_2.Value;
            %numString = ['(sin((',num2str(N),'/2).*',num2str(kd),'.*(sin(theta)-sin(',num2str(deg2rad(theta0)),'))))'];
            %denString = ['(',num2str(N),'*(sin((1/2).*',num2str(kd),'.*(sin(theta)-sin(',num2str(deg2rad(theta0)),')))))'];
            eqString = ['abs((cos(theta)).^',num2str(power),')'];  
            legendEntryString = ['Cosine to ', num2str(power), ' power'];                    
            switch dest
                case 1
                    app.Function1EditField.Value = eqString;
                    app.LegendEntryEditField_1.Value = legendEntryString;
                case 2
                    app.Function2EditField.Value = eqString;
                    app.LegendEntryEditField_2.Value = legendEntryString;
                case 3
                    app.Function3EditField.Value = eqString;
                    app.LegendEntryEditField_3.Value = legendEntryString;
                case 4
                    app.Function4EditField.Value = eqString;
                    app.LegendEntryEditField_4.Value = legendEntryString;
                case 5
                    app.Function5EditField.Value = eqString;
                    app.LegendEntryEditField_5.Value = legendEntryString;
                case 6
                    app.Function6EditField.Value = eqString;
                    app.LegendEntryEditField_6.Value = legendEntryString;
            end  
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button down function: ImportDataTab
        function ImportDataTabButtonDown(app, event)
            if app.importTemplateRead == 0
                t = readtable('importTemplate.xlsx')
                vars = {'theta', 'linear directivity'}
                app.UITable.Data = t;
                app.importTemplateRead = 1;
                app.excelImportedLamp.Enable = 0;
                app.csvImportedLamp.Enable = 0;
                app.matImportedLamp.Enable = 0;
            end
        end

        % Button pushed function: SelectExcelFiletoImportButton
        function SelectExcelFiletoImportButtonPushed(app, event)
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            [excelFile, excelPath] = uigetfile('*.xlsx');
            if isequal(excelFile, 0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(excelPath,excelFile)])
                app.excelImportedLamp.Enable = 1;
                app.csvImportedLamp.Enable = 0;
                app.matImportedLamp.Enable = 0;
                
                t = readtable(fullfile(excelPath, excelFile));
                app.UITable.Data = t;
                
            end
        end

        % Button pushed function: SelectCSVFiletoImportButton
        function SelectCSVFiletoImportButtonPushed(app, event)
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            [csvFile, csvPath] = uigetfile('*.csv');
            if isequal(csvFile, 0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(csvPath,csvFile)])
                app.excelImportedLamp.Enable = 0;
                app.csvImportedLamp.Enable = 1;
                app.matImportedLamp.Enable = 0;
                
                t = readtable(fullfile(csvPath, csvFile));
                app.UITable.Data = t;
            end
        end

        % Button pushed function: SelectmatFiletoImportButton
        function SelectmatFiletoImportButtonPushed(app, event)
            app.PlotTemplateLamp.Enable = 0;
            app.PlotFunctionLamp.Enable = 0;
            [matFile, matPath] = uigetfile('*.mat');
            if isequal(matFile, 0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(matPath,matFile)])
                app.excelImportedLamp.Enable = 0;
                app.csvImportedLamp.Enable = 0;
                app.matImportedLamp.Enable = 1;
                
                dataLoad = load(fullfile(matPath, matFile));
                theta = dataLoad.theta;
                linearDirectivity = dataLoad.linearDirectivity;
                matFileDataArray = [theta' linearDirectivity'];
                t = array2table(matFileDataArray);
                app.UITable.Data = t;
                
            end
        end

        % Value changed function: PlotDataFromTableSwitch
        function PlotDataFromTableSwitchValueChanged(app, event)
            value = app.PlotDataFromTableSwitch.Value;
            app.PlotFunctionLamp.Enable = 0;
            if strcmp(value, 'Off')
                app.plotImportedData = 0;
            elseif strcmp(value, 'On')
                app.plotImportedData = 1;
            end
            %app.plotImportedData = value;
        end

        % Button pushed function: ImportAddButton
        function ImportAddButtonPushed(app, event)
            dest = app.ImportFunctiontoAddtoSpinner.Value;
            % needs work
            tArray = table2array(app.UITable.Data);
            
            importString = '';
            switch app.CurrentImport
                case 1
                    importString = 'app.Import1';
                case 2
                    importString = 'app.Import2';
                case 3
                    importString = 'app.Import3';
                case 4
                    importString = 'app.Import4';
                case 5
                    importString = 'app.Import5';
                case 6
                    importString = 'app.Import6';
            end
            
            %eqString = [importString,'.d'];
            legendEntryString = eval(char([importString,'LegendName']));
            
            switch dest
                case 1
                    app.Function1Imported = 1;
                    app.Function1EditField.FontColor = [1,0,0];
                    %app.Function1EditField.Value = eqString;
                    app.LegendEntryEditField_1.Value = legendEntryString;
                    app.Function1d = eval(char([importString,'d']));
                    app.Function1thetaDeg = eval(char([importString,'thetaDeg']));
                    app.Function1dthetaDeg = eval(char([importString,'dthetaDeg']));
                    app.Function1MinTheta = eval(char([importString,'MinThetaDeg']));
                    app.Function1MaxTheta = eval(char([importString,'MaxThetaDeg']));
                case 2
                    app.Function2Imported = 1;
                    app.Function2EditField.FontColor = [1,0,0];
                    %app.Function2EditField.Value = eqString;
                    app.LegendEntryEditField_2.Value = legendEntryString;
                    app.Function2d = eval(char([importString,'d']));
                    app.Function2thetaDeg = eval(char([importString,'thetaDeg']));
                    app.Function2dthetaDeg = eval(char([importString,'dthetaDeg']));
                    app.Function2MinTheta = eval(char([importString,'MinThetaDeg']));
                    app.Function2MaxTheta = eval(char([importString,'MaxThetaDeg']));
                case 3
                    app.Function3Imported = 1;
                    app.Function3EditField.FontColor = [1,0,0];
                    %app.Function3EditField.Value = eqString;
                    app.LegendEntryEditField_3.Value = legendEntryString;
                    app.Function3d = eval(char([importString,'d']));
                    app.Function3thetaDeg = eval(char([importString,'thetaDeg']));
                    app.Function3dthetaDeg = eval(char([importString,'dthetaDeg']));
                    app.Function3MinTheta = eval(char([importString,'MinThetaDeg']));
                    app.Function3MaxTheta = eval(char([importString,'MaxThetaDeg']));
                case 4
                    app.Function4Imported = 1;
                    app.Function4EditField.FontColor = [1,0,0];
                    %app.Function4EditField.Value = eqString;
                    app.LegendEntryEditField_4.Value = legendEntryString;
                    app.Function4d = eval(char([importString,'d']));
                    app.Function4thetaDeg = eval(char([importString,'thetaDeg']));
                    app.Function4dthetaDeg = eval(char([importString,'dthetaDeg']));
                    app.Function4MinTheta = eval(char([importString,'MinThetaDeg']));
                    app.Function4MaxTheta = eval(char([importString,'MaxThetaDeg']));
                case 5
                    app.Function5Imported = 1;
                    app.Function5EditField.FontColor = [1,0,0];
                    %app.Function5EditField.Value = eqString;
                    app.LegendEntryEditField_5.Value = legendEntryString;
                    app.Function5d = eval(char([importString,'d']));
                    app.Function5thetaDeg = eval(char([importString,'thetaDeg']));
                    app.Function5dthetaDeg = eval(char([importString,'dthetaDeg']));
                    app.Function5MinTheta = eval(char([importString,'MinThetaDeg']));
                    app.Function5MaxTheta = eval(char([importString,'MaxThetaDeg']));
                case 6
                    app.Function6Imported = 1;
                    app.Function6EditField.FontColor = [1,0,0];
                    %app.Function6EditField.Value = eqString;
                    app.LegendEntryEditField_6.Value = legendEntryString;
                    app.Function6d = eval(char([importString,'d']));
                    app.Function6thetaDeg = eval(char([importString,'thetaDeg']));
                    app.Function6dthetaDeg = eval(char([importString,'dthetaDeg']));
                    app.Function6MinTheta = eval(char([importString,'MinThetaDeg']));
                    app.Function6MaxTheta = eval(char([importString,'MaxThetaDeg']));
            end  
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % needs work
            ImportedArray = table2array(app.UITable.Data);            
            Imported.thetaDeg=ImportedArray(:,1);
            Imported.d = ImportedArray(:,2);
            Imported.dthetaDeg = ImportedArray(2,1) - ImportedArray(1,1);
            Imported.MaxThetaDeg = ImportedArray(end,1);
            Imported.MinThetaDeg = ImportedArray(1,1);          
            if app.Import1Button.Value
                app.Import1d = Imported.d;
                app.Import1table = app.UITable.Data;
                app.Import1thetaDeg = Imported.thetaDeg;
                app.Import1dthetaDeg = Imported.dthetaDeg;
                app.Import1MaxThetaDeg = Imported.MaxThetaDeg;
                app.Import1MinThetaDeg = Imported.MinThetaDeg;
                app.Import1FunctionName = 'Import1';
                app.Import1LegendName = 'Import1';
                app.CurrentImport = 1;
                %app.Function1EditField.Value = eqString;
                %app.LegendEntryEditField_1.Value = legendEntryString;
            elseif app.Import2Button.Value
                app.Import2d = Imported.d;
                app.Import2table = app.UITable.Data;
                app.Import2thetaDeg = Imported.thetaDeg;
                app.Import2dthetaDeg = Imported.dthetaDeg;
                app.Import2MaxThetaDeg = Imported.MaxThetaDeg;
                app.Import2MinThetaDeg = Imported.MinThetaDeg;
                app.Import2FunctionName = 'Import2';
                app.Import2LegendName = 'Import2';
                app.CurrentImport = 2;
            elseif app.Import3Button.Value
                app.Import3d = Imported.d;
                app.Import3table = app.UITable.Data;
                app.Import3thetaDeg = Imported.thetaDeg;
                app.Import3dthetaDeg = Imported.dthetaDeg;
                app.Import3MaxThetaDeg = Imported.MaxThetaDeg;
                app.Import3MinThetaDeg = Imported.MinThetaDeg;
                app.Import3FunctionName = 'Import3';
                app.Import3LegendName = 'Import3';
                app.CurrentImport = 3;
            elseif app.Import4Button.Value
                app.Import4d = Imported.d;
                app.Import4table = app.UITable.Data;
                app.Import4thetaDeg = Imported.thetaDeg;
                app.Import4dthetaDeg = Imported.dthetaDeg;
                app.Import4MaxThetaDeg = Imported.MaxThetaDeg;
                app.Import4MinThetaDeg = Imported.MinThetaDeg;
                app.Import4FunctionName = 'Import4';
                app.Import4LegendName = 'Import4';
                app.CurrentImport = 4;
            elseif app.Import5Button.Value    
                app.Import5d = Imported.d;
                app.Import5table = app.UITable.Data;
                app.Import5thetaDeg = Imported.thetaDeg;
                app.Import5dthetaDeg = Imported.dthetaDeg;
                app.Import5MaxThetaDeg = Imported.MaxThetaDeg;
                app.Import5MinThetaDeg = Imported.MinThetaDeg;
                app.Import5FunctionName = 'Import5';
                app.Import5LegendName = 'Import5';
                app.CurrentImport = 5;
            elseif app.Import6Button.Value        
                app.Import6d = Imported.d;
                app.Import6table = app.UITable.Data;
                app.Import6thetaDeg = Imported.thetaDeg;
                app.Import6dthetaDeg = Imported.dthetaDeg;
                app.Import6MaxThetaDeg = Imported.MaxThetaDeg;
                app.Import6MinThetaDeg = Imported.MinThetaDeg;
                app.Import6FunctionName = 'Import6';
                app.Import6LegendName = 'Import6';
                app.CurrentImport = 6;
            end
            
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.Import1Button.Value
                app.UITable.Data = app.Import1table;
                app.CurrentImport = 1;
            elseif app.Import2Button.Value
                app.UITable.Data = app.Import2table;
                app.CurrentImport = 2;
            elseif app.Import3Button.Value
                app.UITable.Data = app.Import3table;
                app.CurrentImport = 3;
            elseif app.Import4Button.Value
                app.UITable.Data = app.Import4table;
                app.CurrentImport = 4;
            elseif app.Import5Button.Value
                app.UITable.Data = app.Import5table;
                app.CurrentImport = 5;
            elseif app.Import6Button.Value
                app.UITable.Data = app.Import6table;        
                app.CurrentImport = 6;
            end
        end

        % Button pushed function: ScaleByMaxValueButton
        function ScaleByMaxValueButtonPushed(app, event)
            ImportedArray = table2array(app.UITable.Data);            
            Imported.thetaDeg=ImportedArray(:,1);
            Imported.d = ImportedArray(:,2);
            Imported.dthetaDeg = ImportedArray(2,1) - ImportedArray(1,1);
            Imported.MaxThetaDeg = ImportedArray(end,1);
            Imported.MinThetaDeg = ImportedArray(1,1);
            disp(['MaxThetaDeg = ', num2str(Imported.MaxThetaDeg)])
            MaxD_Val = max(max(Imported.d));
            NewD = Imported.d ./ MaxD_Val;
            ReplacementArray = ImportedArray;
            ReplacementArray(:,2) = NewD;
            app.UITable.Data = array2table(ReplacementArray);
            
        end

        % Button pushed function: TakeAbsoluteValueButton
        function TakeAbsoluteValueButtonPushed(app, event)
            ImportedArray = table2array(app.UITable.Data);            
            Imported.thetaDeg=ImportedArray(:,1);
            Imported.d = ImportedArray(:,2);
            Imported.dthetaDeg = ImportedArray(2,1) - ImportedArray(1,1);
            Imported.MaxThetaDeg = ImportedArray(end,1);
            Imported.MinThetaDeg = ImportedArray(1,1);
            %disp(['MaxThetaDeg = ', num2str(Imported.MaxThetaDeg)])
            %MaxD_Val = max(max(Imported.d));
            NewD = abs(Imported.d);
            ReplacementArray = ImportedArray;
            ReplacementArray(:,2) = NewD;
            app.UITable.Data = array2table(ReplacementArray);
        end

        % Button pushed function: InsertFirstElementButton
        function InsertFirstElementButtonPushed(app, event)
            ImportedArray = table2array(app.UITable.Data);            
            Imported.thetaDeg=ImportedArray(:,1);
            Imported.d = ImportedArray(:,2);
            Imported.dthetaDeg = ImportedArray(2,1) - ImportedArray(1,1);
            Imported.MaxThetaDeg = ImportedArray(end,1);
            Imported.MinThetaDeg = ImportedArray(1,1);
            
            NewThetaDeg = [Imported.MinThetaDeg-Imported.dthetaDeg;Imported.thetaDeg];
            NewD = [NaN; Imported.d];
            ReplacementArray = NaN(length(NewD), 2);
            ReplacementArray(:,1) = NewThetaDeg;
            ReplacementArray(:,2) = NewD;
            app.UITable.Data = array2table(ReplacementArray);
            
            
        end

        % Button pushed function: InsertLastElementButton
        function InsertLastElementButtonPushed(app, event)
            ImportedArray = table2array(app.UITable.Data);            
            Imported.thetaDeg=ImportedArray(:,1);
            Imported.d = ImportedArray(:,2);
            Imported.dthetaDeg = ImportedArray(2,1) - ImportedArray(1,1);
            Imported.MaxThetaDeg = ImportedArray(end,1);
            Imported.MinThetaDeg = ImportedArray(1,1);
            
            NewThetaDeg = [Imported.thetaDeg; Imported.MaxThetaDeg+Imported.dthetaDeg];
            NewD = [Imported.d ; NaN];
            ReplacementArray = NaN(length(NewD), 2);
            ReplacementArray(:,1) = NewThetaDeg;
            ReplacementArray(:,2) = NewD;
            app.UITable.Data = array2table(ReplacementArray);
        end

        % Button pushed function: RemoveLastElementButton
        function RemoveLastElementButtonPushed(app, event)
            ImportedArray = table2array(app.UITable.Data);            
            Imported.thetaDeg=ImportedArray(:,1);
            Imported.d = ImportedArray(:,2);
            Imported.dthetaDeg = ImportedArray(2,1) - ImportedArray(1,1);
            Imported.MaxThetaDeg = ImportedArray(end,1);
            Imported.MinThetaDeg = ImportedArray(1,1);
            
            NewThetaDeg = Imported.thetaDeg(1:end-1);
            NewD = Imported.d(1:end-1);
            ReplacementArray = NaN(length(NewD), 2);
            ReplacementArray(:,1) = NewThetaDeg;
            ReplacementArray(:,2) = NewD;
            app.UITable.Data = array2table(ReplacementArray);
        end

        % Button pushed function: RemoveFirstElementButton
        function RemoveFirstElementButtonPushed(app, event)
            ImportedArray = table2array(app.UITable.Data);            
            Imported.thetaDeg=ImportedArray(:,1);
            Imported.d = ImportedArray(:,2);
            Imported.dthetaDeg = ImportedArray(2,1) - ImportedArray(1,1);
            Imported.MaxThetaDeg = ImportedArray(end,1);
            Imported.MinThetaDeg = ImportedArray(1,1);
            
            NewThetaDeg = Imported.thetaDeg(2:end);
            NewD = Imported.d(2:end);
            ReplacementArray = NaN(length(NewD), 2);
            ReplacementArray(:,1) = NewThetaDeg;
            ReplacementArray(:,2) = NewD;
            app.UITable.Data = array2table(ReplacementArray);
        end

        % Value changed function: Function1EditField
        function Function1EditFieldValueChanged(app, event)
            value = app.Function1EditField.Value;
            if app.Function1Imported == 1
                app.LegendEntryEditField_1.Value = '';
            end
            app.Function1Imported = 0;
            app.Function1EditField.FontColor = [0,0,0];
        end

        % Value changed function: Function2EditField
        function Function2EditFieldValueChanged(app, event)
            value = app.Function2EditField.Value;
            if app.Function2Imported == 1
                app.LegendEntryEditField_2.Value = '';
            end
            app.Function2Imported = 0;
            app.Function2EditField.FontColor = [0,0,0];
        end

        % Value changed function: Function3EditField
        function Function3EditFieldValueChanged(app, event)
            value = app.Function3EditField.Value;
            if app.Function3Imported == 1
                app.LegendEntryEditField_3.Value = '';
            end
            app.Function3Imported = 0;
            app.Function3EditField.FontColor = [0,0,0];
        end

        % Value changed function: Function4EditField
        function Function4EditFieldValueChanged(app, event)
            value = app.Function4EditField.Value;
            if app.Function4Imported == 1
                app.LegendEntryEditField_4.Value = '';
            end
            app.Function4Imported = 0;
            app.Function4EditField.FontColor = [0,0,0];
        end

        % Value changed function: Function1Button
        function Function1ButtonValueChanged(app, event)
            value = app.Function1Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: Function3Button
        function Function3ButtonValueChanged(app, event)
            value = app.Function3Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: Function2Button
        function Function2ButtonValueChanged(app, event)
            value = app.Function2Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changed function: Function4Button
        function Function4ButtonValueChanged(app, event)
            value = app.Function4Button.Value;
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: GeneratePlotTemplateButton
        function GeneratePlotTemplateButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes, 'reset')
            plot(app.UIAxes, 0, 0);            
            hold(app.UIAxes, 'on');
            ylim(app.UIAxes,[-(app.YMinimumSpinner.Value+0.1), 1.1])
            xlim(app.UIAxes,[-1.1, 1.1])
            pbaspect(app.UIAxes,[2.2/(1.1+0.1+app.YMinimumSpinner.Value) 1 1])    
            if app.LeftButton.Value
                YAxisLocation = 'left';
            elseif app.CenterButton.Value
                YAxisLocation = 'origin';
            else
                YAxisLocation = 'right';
            end
            app.UIAxes.YAxisLocation = YAxisLocation;
            app.UIAxes.YAxis.Color = 'k';
            app.UIAxes.YAxis.FontWeight = 'bold';
            app.UIAxes.YAxis.FontSize = 14;
            app.UIAxes.Layer = 'top';
            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';
            app.UIAxes.YTickMode = 'manual';
            
            %capture user defined Max and Min dB scale value:
            dB_Max = app.dBScaleMaximumSpinner.Value;
            dB_Min = app.dBScaleMinimumSpinner.Value;
            app.dB_Max = dB_Max;
            app.dB_Min = dB_Min;
            
            OneSidedBWs = str2num(char(app.RadialLabelValueOneSidedBWEditField.Value)); 
            if app.LinearScaleButton.Value  % if linear mode:
                Labels = str2num(char(app.RadialLabelValuesDecreasingOrderEditField.Value));
                app.UIAxes.YTick = [0 flip(Labels)];
                title(app.UIAxes,'Linear Directional Factor');
            else                            % else dB mode:
                Labels_dB = str2num(char(app.RadialLabelValuesDecreasingOrderEditField.Value));
                Labels = app.dB2LinScaling4Plots(Labels_dB,dB_Min, dB_Max);
                app.UIAxes.YTick = [flip(Labels)];
                title(app.UIAxes,'dB Directional Factor (Beam Pattern)');
            end
            % Capture user defined ThetaStep value.
            ThetaStep = app.ThetaLabelStepSizeSpinner.Value;
%             ThetaStep = 30;
%             if app.ThetaStep5Button.Value
%                 ThetaStep = 5;
%             elseif app.ThetaStep10Button.Value
%                 ThetaStep = 10;
%             elseif app.ThetaStep15Button.Value
%                 ThetaStep = 15;
%             elseif app.ThetaStep30Button.Value
%                 ThetaStep = 30;
%             elseif app.ThetaStep45Button.Value
%                 ThetaStep = 45;
%             else
%                 ThetaStep = 60;            
%             end
            % capture user defined MaxTheta value.
            MaxTheta = app.MaxThetaSpinner.Value;
            app.MaxTheta = MaxTheta;
            
            % define ThetaSweep based on user input.
            ThetaSweep = -MaxTheta:ThetaStep:MaxTheta;
            
            three_dBLineDrawn = -69;
            if app.HalfPowerHelperLineButton.Value
                three_dBLineDrawn = 0;
            end
            
            dB_ScaleFactor = -1;
            LinearHalfPowerValue = -1;
            if app.dB_Scale10Button.Value
                dB_ScaleFactor = 10;
                LinearHalfPowerValue = 1/2;
            end
            if app.dB_Scale20Button.Value
                dB_ScaleFactor = 20;
                LinearHalfPowerValue = 1/sqrt(2);
            end
            app.dB_ScaleFactor = dB_ScaleFactor;
            %generate the plot template.
            for k = 1:length(Labels)
                degreeRange = -OneSidedBWs(k):1:OneSidedBWs(k);
                [xRange, yRange] = pol2cart(deg2rad(90+degreeRange), Labels(k));
                plot(app.UIAxes, xRange, yRange, 'Color','#848482');


                % if dB Scale:
                if app.dBScaleButton.Value
                    if three_dBLineDrawn == 0
                        if app.HalfPowerHelperLineButton.Value & (Labels(k) > app.dB2LinScaling4Plots(-3,dB_Min, dB_Max)) & (Labels(k+1) < app.dB2LinScaling4Plots(-3, dB_Min, dB_Max))
                            [xRange3db, yRange3db] = pol2cart(deg2rad(90+degreeRange), app.dB2LinScaling4Plots(-3, dB_Min, dB_Max))
                            plot(app.UIAxes, xRange3db, yRange3db, 'Color', '#848482', 'LineStyle', '--')
                            three_dBLineDrawn = 1;
                        end                                                            
                    end
                    if strcmp('origin',YAxisLocation)
                        dB_labelsText = text(app.UIAxes,0.01, 0.005 + app.dB2LinScaling4Plots(Labels_dB(k),dB_Min, dB_Max), 0.1, num2str(Labels_dB(k)));
                    end                
                    if strcmp('right',YAxisLocation)
                        dB_labelsText = text(app.UIAxes,1, 0.002 + app.dB2LinScaling4Plots(Labels_dB(k),dB_Min, dB_Max), 0.1, num2str(Labels_dB(k)));    
                    end
                    if strcmp('left',YAxisLocation)
                        dB_labelsText = text(app.UIAxes, -1.1, 0.002 + app.dB2LinScaling4Plots(Labels_dB(k),dB_Min, dB_Max), 0.1, num2str(Labels_dB(k)));    
                    end
                    dB_labelsText.Color = 'k';
                    dB_labelsText.FontSize = 16;
                    dB_labelsText.FontWeight = 'bold';
                else % if linear scale
                    if three_dBLineDrawn == 0
                        if app.HalfPowerHelperLineButton.Value & (Labels(k)>0.707) & (Labels(k+1)< 0.707)
                            [xRange3db, yRange3db] = pol2cart(deg2rad(90+degreeRange), LinearHalfPowerValue)
                            plot(app.UIAxes, xRange3db, yRange3db, 'Color', '#848482', 'LineStyle', '--')
                            three_dBLineDrawn = 1;
                        end
                    end
                    
                    if strcmp('origin',YAxisLocation)
                        labelsText = text(app.UIAxes, 0.01, 0.005 + (Labels(k)), 0.1, num2str(Labels(k)));
                    end                
                    if strcmp('right',YAxisLocation)
                        labelsText = text(app.UIAxes, 1, 0.002 + (Labels(k)), 0.1, num2str(Labels(k)));    
                    end
                    if strcmp('left',YAxisLocation)
                        labelsText = text(app.UIAxes, -1.1, 0.002 + (Labels(k)), 0.1, num2str(Labels(k)));    
                    end
                    labelsText.Color = 'k';
                    labelsText.FontSize = 16;
                    labelsText.FontWeight = 'bold';                                                        
                end
                
                for thetaSweep = ThetaSweep
                    % detect if the current thetaSweep value falls within the degreeRange of the current k index.
                    if length(degreeRange(degreeRange==thetaSweep)) 
                        % remove current thetaSweep from ThetaSweep so it doesn't
                        % get repeated later!
                        idx = ismember(ThetaSweep, [thetaSweep]);
                        ThetaSweep(idx) = [];
                        % plot the theta line:
                        radiusRange = linspace(0, Labels(k), 100);
                        [xRange2, yRange2] = pol2cart(deg2rad(90+thetaSweep), radiusRange);
                        plot(app.UIAxes, xRange2, yRange2, 'Color', '#848482');
                        txt = num2str(thetaSweep)+"°";
                        Roffset = 0.05;
                        Xoffset = cos(deg2rad(90+thetaSweep))*Roffset;
                        Yoffset = sin(deg2rad(90+thetaSweep))*Roffset;
                        if thetaSweep == -180
                        
                        elseif thetaSweep == 180
                            t = text(app.UIAxes, xRange2(end)+Xoffset, yRange2(end)+Yoffset, txt, 'HorizontalAlignment', 'center'); 
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        elseif thetaSweep == 0
                            t = text(app.UIAxes, xRange2(end)+Xoffset, yRange2(end)+Yoffset, txt, 'HorizontalAlignment', 'center');  
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        elseif thetaSweep < 0                   
                            t = text(app.UIAxes, xRange2(end)+Xoffset, yRange2(end)+Yoffset, txt, 'HorizontalAlignment', 'center');   
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        else                  
                            t = text(app.UIAxes, xRange2(end)+Xoffset, yRange2(end)+Yoffset, txt, 'HorizontalAlignment', 'center');    
                            t.Color = 'k';
                            t.FontSize = 11;
                            t.FontWeight = 'bold';
                        end
                                                                                                                                                                                                                                                                                                              
                    end                
                end                
            end
            legend(app.UIAxes, 'off')
            
            
            app.PlotTemplateLamp.Enable = 1;
            app.PlotFunctionLamp.Enable = 0;
            
        end

        % Button pushed function: PlotFunctionButton
        function PlotFunctionButtonPushed(app, event)
                       
            GeneratePlotTemplateButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
            sweepStep = 0.1;
            plots = [];
            if app.Function1Button.Value
                sumTot = [];
                lenTot = [];
                Func1ColorString = app.LineColorDropDown_1.Value;
                Func1Color = 'k';
                switch Func1ColorString
                    case 'Red'
                        Func1Color = 'r';
                    case 'Green'
                        Func1Color = 'g';
                    case 'Blue'
                        Func1Color = 'b';
                    case 'Cyan'
                        Func1Color = 'c';
                    case 'Magenta'
                        Func1Color = 'm';
                    case 'Yellow'
                        Func1Color = [0.9290, 0.6940, 0.1250];
                    case 'Gray'
                        Func1Color = '#6b6863';
                    case 'Black'
                        Func1Color = 'k';
                    otherwise
                        Func1Color = 'k';
                end
                        
                Func1StyleString = app.LineTypeDropDown_1.Value;
                Func1Style = '-';
                switch Func1StyleString
                    case 'Solid'
                        Func1Style = '-';
                    case 'Dashed'
                        Func1Style = '--';
                    case 'Dotted'
                        Func1Style = ':';
                    case 'Dash-dotted'
                        Func1Style = '-.';
                    otherwise
                        Func1Style = '-';
                end
                
                %sweepStep = 0.1;
                
                
                if app.Function1Imported
                    %theta = eval(char(['app.Import',num2str(app.CurrentImport),'.thetaDeg']));
                    theta = deg2rad(app.Function1thetaDeg);
                    d = app.Function1d;                    
                    sweepStep1 = app.Function1dthetaDeg;
                    MinTheta1 = app.Function1MinTheta;
                    MaxTheta1 = app.Function1MaxTheta + sweepStep1;
                else
                    theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                    theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                    d = abs(eval(char(app.Function1EditField.Value)));
                    sweepStep1 = sweepStep;
                    MinTheta1 = -app.MaxTheta;
                    MaxTheta1 = app.MaxTheta;
                end


                symmetryTest = [];
                offsetVal = 180/sweepStep1;
                if app.MaxTheta == 180 & ~app.Function1Imported
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180 & ~app.Function1Imported
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                if ~strcmp(app.LegendEntryEditField_1.Value,'')
                    fs = app.LegendEntryEditField_1.Value;
                else
                    fs = char(app.Function1EditField.Value);
                    fs(fs=='.')='';
                end
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs, 'Color', Func1Color, 'LineStyle',Func1Style);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
                directivity = app.DirectivityCalc(d, sweepStep1, MinTheta1, MaxTheta1);
                app.DirectivitiesEditField_1.Value = directivity;
            end
            
            if app.Function2Button.Value
                sumTot = [];
                lenTot = [];
                if app.Function2Imported
                    %theta = eval(char(['app.Import',num2str(app.CurrentImport),'.thetaDeg']));
                    theta = deg2rad(app.Function2thetaDeg);
                    d = app.Function2d;
                    sweepStep2 = app.Function2dthetaDeg;
                    MinTheta2 = app.Function2MinTheta;
                    MaxTheta2 = app.Function2MaxTheta + sweepStep2;
                    %theta_neg90_t0_90 = eval(char(['deg2rad(-90:app.Import', num2str(app.CurrentImport),'.dthetaDeg:90)']));
                else
                    theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                    theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                    d = abs(eval(char(app.Function2EditField.Value)));
                    sweepStep2 = sweepStep;
                    MinTheta2 = -app.MaxTheta;
                    MaxTheta2 = app.MaxTheta;
                end
                %sweepStep = 0.1;
                %theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                %theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                Func2ColorString = app.LineColorDropDown_2.Value;
                Func2Color = 'k';
                switch Func2ColorString
                    case 'Red'
                        Func2Color = 'r';
                    case 'Green'
                        Func2Color = 'g';
                    case 'Blue'
                        Func2Color = 'b';
                    case 'Cyan'
                        Func2Color = 'c';
                    case 'Magenta'
                        Func2Color = 'm';
                    case 'Yellow'
                        Func2Color = [0.9290, 0.6940, 0.1250];
                    case 'Gray'
                        Func2Color = '#6b6863';                    
                    case 'Black'
                        Func2Color = 'k';
                    otherwise
                        Func2Color = 'k';
                end
                        
                Func2StyleString = app.LineTypeDropDown_2.Value;
                Func2Style = '-';
                switch Func2StyleString
                    case 'Solid'
                        Func2Style = '-';
                    case 'Dashed'
                        Func2Style = '--';
                    case 'Dotted'
                        Func2Style = ':';
                    case 'Dash-dotted'
                        Func2Style = '-.';
                    otherwise
                        Func2Style = '-';
                end
                
                symmetryTest = [];
                offsetVal = 180/sweepStep2;
                if app.MaxTheta == 180 & ~app.Function2Imported
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180 & ~app.Function2Imported
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                if ~strcmp(app.LegendEntryEditField_2.Value,'')
                    fs = app.LegendEntryEditField_2.Value;
                else
                    fs = char(app.Function2EditField.Value);
                    fs(fs=='.')='';
                end
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs, 'Color', Func2Color, 'LineStyle', Func2Style);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
                directivity = app.DirectivityCalc(d, sweepStep2, MinTheta2, MaxTheta2);
                app.DirectivitiesEditField_2.Value = directivity;
            end
            
            if app.Function3Button.Value
                sumTot = [];
                lenTot = [];
                
                if app.Function3Imported
                    %theta = eval(char(['app.Import',num2str(app.CurrentImport),'.thetaDeg']));
                    theta = deg2rad(app.Function3thetaDeg);
                    d = app.Function3d;
                    sweepStep3 = app.Function3dthetaDeg;
                    MinTheta3 = app.Function3MinTheta;
                    MaxTheta3 = app.Function3MaxTheta + sweepStep3;
                    %theta_neg90_t0_90 = eval(char(['deg2rad(-90:app.Import', num2str(app.CurrentImport),'.dthetaDeg:90)']));
                else
                    theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                    theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                    d = abs(eval(char(app.Function3EditField.Value)));
                    sweepStep3 = sweepStep;
                    MinTheta3 = -app.MaxTheta;
                    MaxTheta3 = app.MaxTheta;
                end
                
                %sweepStep = 0.1;
                %theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                %theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                Func3ColorString = app.LineColorDropDown_3.Value;
                Func3Color = 'k';
                switch Func3ColorString
                    case 'Red'
                        Func3Color = 'r';
                    case 'Green'
                        Func3Color = 'g';
                    case 'Blue'
                        Func3Color = 'b';
                    case 'Cyan'
                        Func3Color = 'c';
                    case 'Magenta'
                        Func3Color = 'm';
                    case 'Yellow'
                        Func3Color = [0.9290, 0.6940, 0.1250];
                    case 'Gray'
                        Func3Color = '#6b6863';
                    case 'Black'
                        Func3Color = 'k';
                    otherwise
                        Func3Color = 'k';
                end
                        
                Func3StyleString = app.LineTypeDropDown_3.Value;
                Func3Style = '-';
                switch Func3StyleString
                    case 'Solid'
                        Func3Style = '-';
                    case 'Dashed'
                        Func3Style = '--';
                    case 'Dotted'
                        Func3Style = ':';
                    case 'Dash-dotted'
                        Func3Style = '-.';
                    otherwise
                        Func3Style = '-';
                end
                
                symmetryTest = [];
                offsetVal = 180/sweepStep3;
                if app.MaxTheta == 180 & ~app.Function3Imported
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180 & ~app.Function3Imported
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                if ~strcmp(app.LegendEntryEditField_3.Value,'')
                    fs = app.LegendEntryEditField_3.Value;
                else
                    fs = char(app.Function3EditField.Value);
                    fs(fs=='.')='';
                end
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs, 'Color', Func3Color, 'LineStyle',Func3Style);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
                directivity = app.DirectivityCalc(d, sweepStep3, MinTheta3, MaxTheta3);
                app.DirectivitiesEditField_3.Value = directivity;
            end
            
            if app.Function4Button.Value
                sumTot = [];
                lenTot = [];
                
                if app.Function4Imported
                    %theta = eval(char(['app.Import',num2str(app.CurrentImport),'.thetaDeg']));
                    theta = deg2rad(app.Function4thetaDeg);
                    d = app.Function4d;
                    sweepStep4 = app.Function4dthetaDeg;
                    MinTheta4 = app.Function4MinTheta;
                    MaxTheta4 = app.Function4MaxTheta + sweepStep4;
                    %theta_neg90_t0_90 = eval(char(['deg2rad(-90:app.Import', num2str(app.CurrentImport),'.dthetaDeg:90)']));
                else
                    theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                    theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                    d = abs(eval(char(app.Function4EditField.Value)));
                    sweepStep4 = sweepStep;
                    MinTheta4 = -app.MaxTheta;
                    MaxTheta4 = app.MaxTheta;
                end
                
                %sweepStep = 0.1;
                %theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                %theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                Func4ColorString = app.LineColorDropDown_4.Value;
                Func4Color = 'k';
                switch Func4ColorString
                    case 'Red'
                        Func4Color = 'r';
                    case 'Green'
                        Func4Color = 'g';
                    case 'Blue'
                        Func4Color = 'b';
                    case 'Cyan'
                        Func4Color = 'c';
                    case 'Magenta'
                        Func4Color = 'm';
                    case 'Yellow'
                        Func4Color = [0.9290, 0.6940, 0.1250];
                    case 'Gray'
                        Func4Color = '#6b6863';
                    case 'Black'
                        Func4Color = 'k';
                    otherwise
                        Func4Color = 'k';
                end
                        
                Func4StyleString = app.LineTypeDropDown_4.Value;
                Func4Style = '-';
                switch Func4StyleString
                    case 'Solid'
                        Func4Style = '-';
                    case 'Dashed'
                        Func4Style = '--';
                    case 'Dotted'
                        Func4Style = ':';
                    case 'Dash-dotted'
                        Func4Style = '-.';
                    otherwise
                        Func4Style = '-';
                end
                
                symmetryTest = [];
                offsetVal = 180/sweepStep4;
                if app.MaxTheta == 180 & ~app.Function4Imported
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180 & app.Function4Imported
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                if ~strcmp(app.LegendEntryEditField_4.Value,'')
                    fs = app.LegendEntryEditField_4.Value;
                else
                    fs = char(app.Function4EditField.Value);
                    fs(fs=='.')='';
                end
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs, 'Color', Func4Color, 'LineStyle', Func4Style);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
                directivity = app.DirectivityCalc(d, sweepStep4, MinTheta4, MaxTheta4);
                app.DirectivitiesEditField_4.Value = directivity;
            end
            
            if app.Function5Button.Value
                sumTot = [];
                lenTot = [];
                
                if app.Function5Imported
                    %theta = eval(char(['app.Import',num2str(app.CurrentImport),'.thetaDeg']));
                    theta = deg2rad(app.Function5thetaDeg);
                    d = app.Function5d;
                    sweepStep5 = app.Function5dthetaDeg;
                    MinTheta5 = app.Function5MinTheta;
                    MaxTheta5 = app.Function5MaxTheta + sweepStep5;
                    %theta_neg90_t0_90 = eval(char(['deg2rad(-90:app.Import', num2str(app.CurrentImport),'.dthetaDeg:90)']));
                else
                    theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                    theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                    d = abs(eval(char(app.Function5EditField.Value)));
                    sweepStep5 = sweepStep;
                    MinTheta5 = -app.MaxTheta;
                    MaxTheta5 = app.MaxTheta;
                end
                
                %sweepStep = 0.1;
                %theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                %theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                Func5ColorString = app.LineColorDropDown_5.Value;
                Func5Color = 'k';
                switch Func5ColorString
                    case 'Red'
                        Func5Color = 'r';
                    case 'Green'
                        Func5Color = 'g';
                    case 'Blue'
                        Func5Color = 'b';
                    case 'Cyan'
                        Func5Color = 'c';
                    case 'Magenta'
                        Func5Color = 'm';
                    case 'Yellow'
                        Func5Color = [0.9290, 0.6940, 0.1250];
                    case 'Gray'
                        Func5Color = '#6b6863';
                    case 'Black'
                        Func5Color = 'k';
                    otherwise
                        Func5Color = 'k';
                end
                        
                Func5StyleString = app.LineTypeDropDown_5.Value;
                Func5Style = '-';
                switch Func5StyleString
                    case 'Solid'
                        Func5Style = '-';
                    case 'Dashed'
                        Func5Style = '--';
                    case 'Dotted'
                        Func5Style = ':';
                    case 'Dash-dotted'
                        Func5Style = '-.';
                    otherwise
                        Func5Style = '-';
                end
                
                symmetryTest = [];
                offsetVal = 180/sweepStep5;
                if app.MaxTheta == 180 & ~app.Function5Imported
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180 & app.Function5Imported
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                if ~strcmp(app.LegendEntryEditField_5.Value,'')
                    fs = app.LegendEntryEditField_5.Value;
                else
                    fs = char(app.Function5EditField.Value);
                    fs(fs=='.')='';
                end
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs, 'Color', Func5Color, 'LineStyle', Func5Style);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
                directivity = app.DirectivityCalc(d, sweepStep5, MinTheta5, MaxTheta5);
                app.DirectivitiesEditField_5.Value = directivity;
            end
            
            if app.Function6Button.Value
                sumTot = [];
                lenTot = [];
                
                if app.Function6Imported
                    %theta = eval(char(['app.Import',num2str(app.CurrentImport),'.thetaDeg']));
                    theta = deg2rad(app.Function6thetaDeg);
                    d = app.Function6d;
                    sweepStep6 = app.Function6dthetaDeg;
                    MinTheta6 = app.Function6MinTheta;
                    MaxTheta6 = app.Function6MaxTheta + sweepStep6;
                    %theta_neg90_t0_90 = eval(char(['deg2rad(-90:app.Import', num2str(app.CurrentImport),'.dthetaDeg:90)']));
                else
                    theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                    theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                    d = abs(eval(char(app.Function6EditField.Value)));
                    sweepStep6 = sweepStep;
                    MinTheta6 = -app.MaxTheta;
                    MaxTheta6 = app.MaxTheta;
                end
                
                %sweepStep = 0.1;
                %theta = deg2rad(-app.MaxTheta:sweepStep:app.MaxTheta-sweepStep);
                %theta_neg90_to_90 = deg2rad(-90:sweepStep:90);
                
                Func6ColorString = app.LineColorDropDown_6.Value;
                Func6Color = 'k';
                switch Func6ColorString
                    case 'Red'
                        Func6Color = 'r';
                    case 'Green'
                        Func6Color = 'g';
                    case 'Blue'
                        Func6Color = 'b';
                    case 'Cyan'
                        Func6Color = 'c';
                    case 'Magenta'
                        Func6Color = 'm';
                    case 'Yellow'
                        Func6Color = [0.9290, 0.6940, 0.1250];
                    case 'Gray'
                        Func6Color = '#6b6863';
                    case 'Black'
                        Func6Color = 'k';
                    otherwise
                        Func6Color = 'k';
                end
                        
                Func6StyleString = app.LineTypeDropDown_6.Value;
                Func6Style = '-';
                Func6Marker = 'none';
                switch Func6StyleString
                    case 'Solid'
                        Func6Style = '-';
                    case 'Dashed'
                        Func6Style = '--';
                    case 'Dotted'
                        Func6Style = ':';
                    case 'Dash-dotted'
                        Func6Style = '-.';
                    case 'Star'
                        Func6Style = 'none';
                        Func6Marker = '*';
                    otherwise
                        Func6Style = '-';
                end
                
                symmetryTest = [];
                offsetVal = 180/sweepStep6;
                if app.MaxTheta == 180 & ~app.Function6Imported
                    for m = 1:offsetVal
                        symmetryTest = [symmetryTest abs(d(m)-d(m+offsetVal))<0.000000000001];
                    end
                end
                sumTot = [sumTot sum(symmetryTest)];
                lenTot = [lenTot length(symmetryTest)];
                
                if (sum(symmetryTest) == length(symmetryTest)) & app.discardRedundant & app.MaxTheta == 180 & ~app.Function6Imported
                    %only plot -90 to 90 deg..
                    %idxNeg90 = ismember(theta, [-90])
                    idxNeg90 = find(theta==-pi/2)
                    %idx90 = ismember(theta, [90])
                    idx90 = find(theta==pi/2)
                    d = d(idxNeg90:idx90);
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta_neg90_to_90+pi/2, d);
                    end
                else    
                    if app.dBScaleButton.Value
                        d_dB = app.dB_ScaleFactor.*log10(d);
                        d_dB(d_dB < app.dB_Min) = app.dB_Min;
                        d_dB(d_dB > app.dB_Max) = app.dB_Max;
                        d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                        [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                    else
                        [xDir, yDir] = pol2cart(theta+pi/2, d);
                    end                
                end
                if ~strcmp(app.LegendEntryEditField_6.Value,'')
                    fs = app.LegendEntryEditField_6.Value;
                else
                    fs = char(app.Function6EditField.Value);
                    fs(fs=='.')='';
                end
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs, 'Color', Func6Color, 'LineStyle', Func6Style, 'Marker', Func6Marker, 'MarkerIndices', 1:60:length(yDir));
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
                directivity = app.DirectivityCalc(d, sweepStep6, MinTheta6, MaxTheta6);
                app.DirectivitiesEditField_6.Value = directivity;
            end
            
            if app.plotImportedData
                
                theta = deg2rad((app.UITable.Data{:,1}));
                d = (app.UITable.Data{:,2});                
                    
                if app.dBScaleButton.Value
                    d_dB = app.dB_ScaleFactor.*log10(d);
                    d_dB(d_dB < app.dB_Min) = app.dB_Min;
                    d_dB(d_dB > app.dB_Max) = app.dB_Max;
                    d_LinRepresentation = app.dB2LinScaling4Plots(d_dB);
                    [xDir, yDir] = pol2cart(theta+pi/2, d_LinRepresentation);
                else
                    [xDir, yDir] = pol2cart(theta+pi/2, d);
                end                
                
                fs = 'Imported Function';
                
                plt = plot(app.UIAxes, xDir, yDir, 'LineWidth', 2, 'DisplayName', fs);
                plots = [plots plt];
                
                legend(app.UIAxes, plots, 'FontSize', 12)
            end
            
            if app.LegendOnButton.Value
                if strcmp(app.LegendLocationDropDown.Value,'North')                                   
                    legend(app.UIAxes, 'Orientation','horizontal');
                    legend(app.UIAxes, 'Location','northoutside');    
                end
                if strcmp(app.LegendLocationDropDown.Value,'South')                                   
                    legend(app.UIAxes, 'Orientation','horizontal');
                    legend(app.UIAxes, 'Location','southoutside');    
                end
                if strcmp(app.LegendLocationDropDown.Value, 'East')
                    legend(app.UIAxes, 'Orientation', 'vertical');
                    legend(app.UIAxes, 'Location','eastoutside');                    
                end
                if strcmp(app.LegendLocationDropDown.Value, 'West')
                    legend(app.UIAxes, 'Orientation', 'vertical');
                    legend(app.UIAxes, 'Location','westoutside');                    
                end
                if strcmp(app.LegendLocationDropDown.Value, 'North West')
                    legend(app.UIAxes, 'Orientation', 'vertical');
                    legend(app.UIAxes, 'Location','northwestoutside');                    
                end
                if strcmp(app.LegendLocationDropDown.Value, 'North East')
                    legend(app.UIAxes, 'Orientation', 'vertical');
                    legend(app.UIAxes, 'Location','northeastoutside');                    
                end
                if strcmp(app.LegendLocationDropDown.Value, 'South West')
                    legend(app.UIAxes, 'Orientation', 'vertical');
                    legend(app.UIAxes, 'Location','southwestoutside');                    
                end
                if strcmp(app.LegendLocationDropDown.Value, 'South East')
                    legend(app.UIAxes, 'Orientation', 'vertical');
                    legend(app.UIAxes, 'Location','southeastoutside');                    
                end
                legend(app.UIAxes, 'show');
            else
                legend(app.UIAxes, 'hide')
            end
            
            app.UIAxes.Toolbar.Visible = 'on';
            app.PlotFunctionLamp.Enable = 1;
        end

        % Value changed function: Function5EditField
        function Function5EditFieldValueChanged(app, event)
            value = app.Function5EditField.Value;
            if app.Function5Imported == 1
                app.LegendEntryEditField_5.Value = '';
            end
            
            app.Function5Imported = 0;
            app.Function5EditField.FontColor = [0,0,0];
            app.PlotFunctionLamp.Enable = 0;
        end

        % Value changing function: Function5EditField
        function Function5EditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.Function5Imported = 0;
        end

        % Value changed function: Function6EditField
        function Function6EditFieldValueChanged(app, event)
            value = app.Function6EditField.Value;
            
            if app.Function6Imported == 1
                app.LegendEntryEditField_6.Value = '';
            end
            
            app.Function6Imported = 0;
            app.Function6EditField.FontColor = [0,0,0];
            app.PlotFunctionLamp.Enable = 0;
        end

        % Button pushed function: 
        % OpenExternalFigureWindowforSavingCopyingImageButton
        function OpenExternalFigureWindowforSavingCopyingImageButtonPushed(app, event)
            ax = axes();
            ax.XAxis.Visible = 'off';
            ax.YAxis.Visible = 'off';
            copyUIAxes(app.UIAxes, ax);                       
            export_fig ax -clipboard 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SmartPolarPlotsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1080, 1080};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {662, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmartPolarPlotsUIFigure = uifigure('Visible', 'off');
            app.SmartPolarPlotsUIFigure.Name = 'Smart Polar Plots';
            app.SmartPolarPlotsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SmartPolarPlotsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionDockPanel = uipanel(app.LeftPanel);
            app.FunctionDockPanel.Title = 'Function Dock';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PlotSetupTab = uitab(app.TabGroup);
            app.PlotSetupTab.Tooltip = {''};
            app.PlotSetupTab.Title = 'Plot Setup';
            app.PlotStyleButtonGroup = uibuttongroup(app.PlotSetupTab);
            app.PlotStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotStyleButtonGroupSelectionChanged, true);
            app.PlotStyleButtonGroup.Title = 'Plot Style';
            app.dBScaleButton = uiradiobutton(app.PlotStyleButtonGroup);
            app.dBScaleButton.Text = 'dB Scale';
            app.LinearScaleButton = uiradiobutton(app.PlotStyleButtonGroup);
            app.LinearScaleButton.Text = 'Linear Scale';
            app.LinearScaleButton.Value = true;
            app.MaxThetaSpinnerLabel = uilabel(app.PlotSetupTab);
            app.MaxThetaSpinnerLabel.Text = 'Max Theta';
            app.MaxThetaSpinner = uispinner(app.PlotSetupTab);
            app.MaxThetaSpinner.Step = 5;
            app.MaxThetaSpinner.Limits = [0 180];
            app.MaxThetaSpinner.RoundFractionalValues = 'on';
            app.MaxThetaSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxThetaSpinnerValueChanged, true);
            app.MaxThetaSpinner.Value = 180;
            app.ScaleFactorButtonGroup = uibuttongroup(app.PlotSetupTab);
            app.ScaleFactorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScaleFactorButtonGroupSelectionChanged, true);
            app.ScaleFactorButtonGroup.Title = 'Scale Factor';
            app.dB_Scale10Button = uiradiobutton(app.ScaleFactorButtonGroup);
            app.dB_Scale10Button.Text = '10 (Power)';
            app.dB_Scale20Button = uiradiobutton(app.ScaleFactorButtonGroup);
            app.dB_Scale20Button.Text = '20 (Voltage)';
            app.dB_Scale20Button.Value = true;
            app.YMinimumSpinnerLabel = uilabel(app.PlotSetupTab);
            app.YMinimumSpinnerLabel.Text = 'Y Minimum';
            app.YMinimumSpinner = uispinner(app.PlotSetupTab);
            app.YMinimumSpinner.Step = 0.1;
            app.YMinimumSpinner.Limits = [0 1];
            app.YMinimumSpinner.ValueDisplayFormat = '%1.1g';
            app.YMinimumSpinner.ValueChangedFcn = createCallbackFcn(app, @YMinimumSpinnerValueChanged, true);
            app.YMinimumSpinner.Value = 1;
            app.RadialLabelValuesDecreasingOrderEditFieldLabel = uilabel(app.PlotSetupTab);
            app.RadialLabelValuesDecreasingOrderEditFieldLabel.Text = 'Radial Label Values: Decreasing Order';
            app.RadialLabelValuesDecreasingOrderEditField = uieditfield(app.PlotSetupTab, 'text');
            app.RadialLabelValuesDecreasingOrderEditField.ValueChangedFcn = createCallbackFcn(app, @RadialLabelValuesDecreasingOrderEditFieldValueChanged, true);
            app.RadialLabelValuesDecreasingOrderEditField.Placeholder = '[1, 0.8, 0.6, 0.4, 0.2]';
            app.RadialLabelValuesDecreasingOrderEditField.Value = '[1, 0.8, 0.6, 0.4, 0.2]';
            app.RadialLabelValueOneSidedBWEditFieldLabel = uilabel(app.PlotSetupTab);
            app.RadialLabelValueOneSidedBWEditFieldLabel.Text = 'Radial Label Value One Sided BW';
            app.RadialLabelValueOneSidedBWEditField = uieditfield(app.PlotSetupTab, 'text');
            app.RadialLabelValueOneSidedBWEditField.ValueChangedFcn = createCallbackFcn(app, @RadialLabelValueOneSidedBWEditFieldValueChanged, true);
            app.RadialLabelValueOneSidedBWEditField.Placeholder = '[180, 180, 180, 180, 180]';
            app.RadialLabelValueOneSidedBWEditField.Value = '[180, 180, 180, 180, 180]';
            app.RadialLabelLocationButtonGroup = uibuttongroup(app.PlotSetupTab);
            app.RadialLabelLocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadialLabelLocationButtonGroupSelectionChanged, true);
            app.RadialLabelLocationButtonGroup.Title = 'Radial Label Location';
            app.LeftButton = uiradiobutton(app.RadialLabelLocationButtonGroup);
            app.LeftButton.Text = 'Left';
            app.CenterButton = uiradiobutton(app.RadialLabelLocationButtonGroup);
            app.CenterButton.Text = 'Center';
            app.CenterButton.Value = true;
            app.RightButton = uiradiobutton(app.RadialLabelLocationButtonGroup);
            app.RightButton.Text = 'Right';
            app.dBScaleMinimumSpinnerLabel = uilabel(app.PlotSetupTab);
            app.dBScaleMinimumSpinnerLabel.Text = 'dB Scale Minimum';
            app.dBScaleMinimumSpinner = uispinner(app.PlotSetupTab);
            app.dBScaleMinimumSpinner.Step = 5;
            app.dBScaleMinimumSpinner.RoundFractionalValues = 'on';
            app.dBScaleMinimumSpinner.ValueChangedFcn = createCallbackFcn(app, @dBScaleMinimumSpinnerValueChanged, true);
            app.dBScaleMinimumSpinner.Value = -40;
            app.dBScaleMaximumSpinnerLabel = uilabel(app.PlotSetupTab);
            app.dBScaleMaximumSpinnerLabel.Text = 'dB Scale Maximum';
            app.dBScaleMaximumSpinner = uispinner(app.PlotSetupTab);
            app.dBScaleMaximumSpinner.Step = 5;
            app.dBScaleMaximumSpinner.RoundFractionalValues = 'on';
            app.dBScaleMaximumSpinner.ValueChangedFcn = createCallbackFcn(app, @dBScaleMaximumSpinnerValueChanged, true);
            app.HalfPowerHelperLineButton = uibutton(app.PlotSetupTab, 'state');
            app.HalfPowerHelperLineButton.ValueChangedFcn = createCallbackFcn(app, @HalfPowerHelperLineButtonValueChanged, true);
            app.HalfPowerHelperLineButton.Text = 'Half Power Helper Line';
            app.Hyperlink = uihyperlink(app.PlotSetupTab);
            app.Hyperlink.URL = 'https://github.com/mbisbano1/MatlabSmartPolarPlots/blob/main/README.md';
            app.Hyperlink.Text = 'Help';
            app.HelperLineLamp = uilamp(app.PlotSetupTab);
            app.HelperLineLamp.Enable = 'off';
            app.DefaultSettingsModesPanel = uipanel(app.PlotSetupTab);
            app.DefaultSettingsModesPanel.Title = 'Default Settings Modes';
            app.Full360Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Full360Button.ButtonPushedFcn = createCallbackFcn(app, @Full360ButtonPushed, true);
            app.Full360Button.Text = 'Full 360°';
            app.Full180Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Full180Button.ButtonPushedFcn = createCallbackFcn(app, @Full180ButtonPushed, true);
            app.Full180Button.Text = 'Full 180°';
            app.Tiered3602Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered3602Button.ButtonPushedFcn = createCallbackFcn(app, @Tiered3602ButtonPushed, true);
            app.Tiered3602Button.Text = 'Tiered 360° #2';
            app.Tiered180Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered180Button.ButtonPushedFcn = createCallbackFcn(app, @Tiered180ButtonPushed, true);
            app.Tiered180Button.Text = 'Tiered 180°';
            app.Tiered1802Button = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered1802Button.ButtonPushedFcn = createCallbackFcn(app, @Tiered1802ButtonPushed, true);
            app.Tiered1802Button.Text = 'Tiered 180° #2';
            app.Tiered360Button_2 = uibutton(app.DefaultSettingsModesPanel, 'push');
            app.Tiered360Button_2.ButtonPushedFcn = createCallbackFcn(app, @Tiered360Button_2Pushed, true);
            app.Tiered360Button_2.Text = 'Tiered 360°';
            app.DiscardRedundantMirrorsButton = uibutton(app.PlotSetupTab, 'state');
            app.DiscardRedundantMirrorsButton.ValueChangedFcn = createCallbackFcn(app, @DiscardRedundantMirrorsButtonValueChanged, true);
            app.DiscardRedundantMirrorsButton.Text = 'Discard Redundant Mirrors';
            app.DiscardRedundantLamp = uilamp(app.PlotSetupTab);
            app.DiscardRedundantLamp.Enable = 'off';
            app.ThetaLabelStepSizeSpinnerLabel = uilabel(app.PlotSetupTab);
            app.ThetaLabelStepSizeSpinnerLabel.Text = 'Theta Label Step Size';
            app.ThetaLabelStepSizeSpinner = uispinner(app.PlotSetupTab);
            app.ThetaLabelStepSizeSpinner.Step = 0.1;
            app.ThetaLabelStepSizeSpinner.Limits = [0.1 90];
            app.ThetaLabelStepSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @ThetaLabelStepSizeSpinnerValueChanged, true);
            app.ThetaLabelStepSizeSpinner.Value = 30;
            app.CustomSettingsFilesPanel = uipanel(app.PlotSetupTab);
            app.CustomSettingsFilesPanel.Title = 'Custom Settings Files:';
            app.ExportSettingsButton = uibutton(app.CustomSettingsFilesPanel, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            app.LoadSettingsButton = uibutton(app.CustomSettingsFilesPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.LegendSettingsPanel = uipanel(app.PlotSetupTab);
            app.LegendSettingsPanel.Title = 'Legend Settings:';
            app.LegendLocationDropDownLabel = uilabel(app.LegendSettingsPanel);
            app.LegendLocationDropDownLabel.Text = 'Legend Location';
            app.LegendLocationDropDown = uidropdown(app.LegendSettingsPanel);
            app.LegendLocationDropDown.Items = {'North', 'South', 'West', 'East', 'North West', 'North East', 'South West', 'South East'};
            app.LegendLocationDropDown.Value = 'South';
            app.LegendOnButton = uibutton(app.LegendSettingsPanel, 'state');
            app.LegendOnButton.Tooltip = {'Toggle Visibility of the Plot Legend'};
            app.LegendOnButton.Text = 'LegendOn';
            app.LegendOnButton.Value = true;
            app.PredefinedPatternsTab = uitab(app.TabGroup);
            app.PredefinedPatternsTab.Tooltip = {'Select from, configure, and load predefined patterns into the plot functions.'};
            app.PredefinedPatternsTab.Title = 'Predefined Patterns';
            app.ContinuousLineSourcesPanel = uipanel(app.PredefinedPatternsTab);
            app.ContinuousLineSourcesPanel.Title = 'Continuous Line Sources:';
            app.FunctiontoAddtoSpinnerLabel = uilabel(app.ContinuousLineSourcesPanel);
            app.FunctiontoAddtoSpinnerLabel.Text = 'Function to Add to:';
            app.FunctiontoAddtoSpinner = uispinner(app.ContinuousLineSourcesPanel);
            app.FunctiontoAddtoSpinner.Limits = [1 6];
            app.FunctiontoAddtoSpinner.RoundFractionalValues = 'on';
            app.FunctiontoAddtoSpinner.Value = 1;
            app.AddButton = uibutton(app.ContinuousLineSourcesPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel = uilabel(app.ContinuousLineSourcesPanel);
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel.Interpreter = 'latex';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel.Text = '$H(\theta) = \left|\frac{sin(v)}{v}\right|   ,\text{  where     } v=kL*sin(\theta)$';
            app.kLEditField_2Label = uilabel(app.ContinuousLineSourcesPanel);
            app.kLEditField_2Label.Text = 'kL';
            app.kLEditField = uieditfield(app.ContinuousLineSourcesPanel, 'text');
            app.kLEditField.Placeholder = '1*pi';
            app.kLEditField.Value = '1*pi';
            app.PlaneCircularPistonSourcesPanel = uipanel(app.PredefinedPatternsTab);
            app.PlaneCircularPistonSourcesPanel.Title = 'Plane Circular Piston Sources: ';
            app.FunctiontoAddtoSpinner_2Label = uilabel(app.PlaneCircularPistonSourcesPanel);
            app.FunctiontoAddtoSpinner_2Label.Text = 'Function to Add to:';
            app.FunctiontoAddtoSpinner_2 = uispinner(app.PlaneCircularPistonSourcesPanel);
            app.FunctiontoAddtoSpinner_2.Limits = [1 6];
            app.FunctiontoAddtoSpinner_2.RoundFractionalValues = 'on';
            app.FunctiontoAddtoSpinner_2.Value = 1;
            app.AddButton_2 = uibutton(app.PlaneCircularPistonSourcesPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.Hthetaleftfrac2J_1vvrighttextwherevkasinthetaLabel = uilabel(app.PlaneCircularPistonSourcesPanel);
            app.Hthetaleftfrac2J_1vvrighttextwherevkasinthetaLabel.Interpreter = 'latex';
            app.Hthetaleftfrac2J_1vvrighttextwherevkasinthetaLabel.Text = '$H(\theta) = \left|\frac{2J_1(v)}{v}\right|   ,\text{  where     } v=ka*sin(\theta)$';
            app.kaEditFieldLabel = uilabel(app.PlaneCircularPistonSourcesPanel);
            app.kaEditFieldLabel.Interpreter = 'latex';
            app.kaEditFieldLabel.Text = '$ka$';
            app.kaEditField = uieditfield(app.PlaneCircularPistonSourcesPanel, 'text');
            app.kaEditField.Placeholder = '1*pi';
            app.kaEditField.Value = '1*pi';
            app.ContinuousLineArraySourcesPanel = uipanel(app.PredefinedPatternsTab);
            app.ContinuousLineArraySourcesPanel.Title = 'Continuous Line Array Sources:';
            app.theta_0degreesEditFieldLabel = uilabel(app.ContinuousLineArraySourcesPanel);
            app.theta_0degreesEditFieldLabel.Interpreter = 'latex';
            app.theta_0degreesEditFieldLabel.Text = '$\theta_0$ (degrees)';
            app.theta_0degreesEditField = uieditfield(app.ContinuousLineArraySourcesPanel, 'numeric');
            app.FunctiontoAddtoSpinner_3Label = uilabel(app.ContinuousLineArraySourcesPanel);
            app.FunctiontoAddtoSpinner_3Label.Text = 'Function to Add to:';
            app.FunctiontoAddtoSpinner_3 = uispinner(app.ContinuousLineArraySourcesPanel);
            app.FunctiontoAddtoSpinner_3.Limits = [1 6];
            app.FunctiontoAddtoSpinner_3.RoundFractionalValues = 'on';
            app.FunctiontoAddtoSpinner_3.Value = 1;
            app.AddButton_3 = uibutton(app.ContinuousLineArraySourcesPanel, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_3 = uilabel(app.ContinuousLineArraySourcesPanel);
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_3.Interpreter = 'latex';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_3.Text = '$\theta_0 \text{ is steered angle, } N \text{ is Num Elements}$';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_2 = uilabel(app.ContinuousLineArraySourcesPanel);
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_2.Interpreter = 'latex';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_2.Text = '$H(\theta) = \left|  \frac{1}{N}\frac{sin[(N/2)kd(sin(\theta)-sin(\theta_0))]}{sin[(1/2)kd(sin(\theta)-sin(\theta_0))]}         \right| $';
            app.NLabel = uilabel(app.ContinuousLineArraySourcesPanel);
            app.NLabel.Interpreter = 'latex';
            app.NLabel.Text = '$N$';
            app.NEditField = uieditfield(app.ContinuousLineArraySourcesPanel, 'numeric');
            app.NEditField.Limits = [1 3000];
            app.NEditField.RoundFractionalValues = 'on';
            app.NEditField.Value = 10;
            app.kdLabel_2 = uilabel(app.ContinuousLineArraySourcesPanel);
            app.kdLabel_2.Interpreter = 'latex';
            app.kdLabel_2.Text = '$kd$';
            app.kdEditField = uieditfield(app.ContinuousLineArraySourcesPanel, 'text');
            app.kdEditField.Placeholder = '1*pi';
            app.kdEditField.Value = '1*pi';
            app.Hyperlink_3 = uihyperlink(app.PredefinedPatternsTab);
            app.Hyperlink_3.URL = 'https://github.com/mbisbano1/MatlabSmartPolarPlots/blob/main/README.md';
            app.Hyperlink_3.Text = 'Help';
            app.CardioidSourcePanel = uipanel(app.PredefinedPatternsTab);
            app.CardioidSourcePanel.Title = 'Cardioid Source:';
            app.FunctiontoAddtoSpinner_4Label_2 = uilabel(app.CardioidSourcePanel);
            app.FunctiontoAddtoSpinner_4Label_2.Text = 'Function to Add to:';
            app.FunctiontoAddtoSpinner_4 = uispinner(app.CardioidSourcePanel);
            app.FunctiontoAddtoSpinner_4.Limits = [1 6];
            app.FunctiontoAddtoSpinner_4.RoundFractionalValues = 'on';
            app.FunctiontoAddtoSpinner_4.Value = 1;
            app.AddButton_4 = uibutton(app.CardioidSourcePanel, 'push');
            app.AddButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddButton_4Pushed, true);
            app.AddButton_4.Text = 'Add';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_5 = uilabel(app.CardioidSourcePanel);
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_5.Interpreter = 'latex';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_5.Text = '$H(\theta) = \frac{(1+cos(\theta - \theta_0)) }{2}';
            app.theta_0degreesEditFieldLabel_2 = uilabel(app.CardioidSourcePanel);
            app.theta_0degreesEditFieldLabel_2.Interpreter = 'latex';
            app.theta_0degreesEditFieldLabel_2.Text = '$\theta_0$ (degrees)';
            app.theta_0degreesEditField_2 = uieditfield(app.CardioidSourcePanel, 'numeric');
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_6 = uilabel(app.CardioidSourcePanel);
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_6.Interpreter = 'latex';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_6.Text = '$\theta_0 \text{ is steered angle} $';
            app.CosinetoPowerPanel = uipanel(app.PredefinedPatternsTab);
            app.CosinetoPowerPanel.Title = 'Cosine to Power:';
            app.FunctiontoAddtoSpinner_5Label = uilabel(app.CosinetoPowerPanel);
            app.FunctiontoAddtoSpinner_5Label.Text = 'Function to Add to:';
            app.FunctiontoAddtoSpinner_5 = uispinner(app.CosinetoPowerPanel);
            app.FunctiontoAddtoSpinner_5.Limits = [1 6];
            app.FunctiontoAddtoSpinner_5.RoundFractionalValues = 'on';
            app.FunctiontoAddtoSpinner_5.Value = 1;
            app.AddButton_5 = uibutton(app.CosinetoPowerPanel, 'push');
            app.AddButton_5.ButtonPushedFcn = createCallbackFcn(app, @AddButton_5Pushed, true);
            app.AddButton_5.Text = 'Add';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_7 = uilabel(app.CosinetoPowerPanel);
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_7.Interpreter = 'latex';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_7.Text = '$H(\theta) = (cos(\theta))^m';
            app.theta_0degreesEditFieldLabel_3 = uilabel(app.CosinetoPowerPanel);
            app.theta_0degreesEditFieldLabel_3.Interpreter = 'latex';
            app.theta_0degreesEditFieldLabel_3.Text = 'm';
            app.theta_0degreesEditField_3 = uieditfield(app.CosinetoPowerPanel, 'numeric');
            app.theta_0degreesEditField_3.Value = 1;
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_8 = uilabel(app.CosinetoPowerPanel);
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_8.Interpreter = 'latex';
            app.HthetaleftfracsinvvrighttextwherevkLsinthetaLabel_8.Text = '$\text{m is the cosine''s power} $';
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Tooltip = {'Import and manipulate data from external sources for plotting'};
            app.ImportDataTab.Title = 'Import Data';
            app.ImportDataTab.ButtonDownFcn = createCallbackFcn(app, @ImportDataTabButtonDown, true);
            app.SelectExcelFiletoImportButton = uibutton(app.ImportDataTab, 'push');
            app.SelectExcelFiletoImportButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelFiletoImportButtonPushed, true);
            app.SelectExcelFiletoImportButton.Tooltip = {'Import Directivity Data from an XLSX file'};
            app.SelectExcelFiletoImportButton.Text = 'Select Excel File to Import';
            app.SelectCSVFiletoImportButton = uibutton(app.ImportDataTab, 'push');
            app.SelectCSVFiletoImportButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCSVFiletoImportButtonPushed, true);
            app.SelectCSVFiletoImportButton.Tooltip = {'Import Directivity Data from a CSV file'};
            app.SelectCSVFiletoImportButton.Text = 'Select CSV File to Import';
            app.UITable = uitable(app.ImportDataTab);
            app.UITable.ColumnName = {'theta'; 'linearDF'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SelectmatFiletoImportButton = uibutton(app.ImportDataTab, 'push');
            app.SelectmatFiletoImportButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmatFiletoImportButtonPushed, true);
            app.SelectmatFiletoImportButton.Tooltip = {'Import Directivity Data from a MAT matlab data file'};
            app.SelectmatFiletoImportButton.Text = 'Select .mat File to Import';
            app.matImportedLamp = uilamp(app.ImportDataTab);
            app.matImportedLamp.Enable = 'off';
            app.csvImportedLamp = uilamp(app.ImportDataTab);
            app.csvImportedLamp.Enable = 'off';
            app.excelImportedLamp = uilamp(app.ImportDataTab);
            app.excelImportedLamp.Enable = 'off';
            app.PlotDataFromTableSwitchLabel = uilabel(app.ImportDataTab);
            app.PlotDataFromTableSwitchLabel.Text = 'Plot Data From Table?';
            app.PlotDataFromTableSwitch = uiswitch(app.ImportDataTab, 'slider');
            app.PlotDataFromTableSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotDataFromTableSwitchValueChanged, true);
            app.PlotDataFromTableSwitch.Tooltip = {'Plot Data from Table Workspace directly, without loading into a function. This is not recommended, Directivity will not be calculated.'};
            app.Hyperlink_2 = uihyperlink(app.ImportDataTab);
            app.Hyperlink_2.URL = 'https://github.com/mbisbano1/MatlabSmartPolarPlots/blob/main/README.md';
            app.Hyperlink_2.Text = 'Help';
            app.ImportAddButton = uibutton(app.ImportDataTab, 'push');
            app.ImportAddButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAddButtonPushed, true);
            app.ImportAddButton.Tooltip = {'Add Current Data from Selected Import Slot  (above) into Selected (left) Function '};
            app.ImportAddButton.Text = 'Add';
            app.FunctiontoDisplayonLabel = uilabel(app.ImportDataTab);
            app.FunctiontoDisplayonLabel.Text = 'Function to Display on:';
            app.ImportFunctiontoAddtoSpinner = uispinner(app.ImportDataTab);
            app.ImportFunctiontoAddtoSpinner.Limits = [1 6];
            app.ImportFunctiontoAddtoSpinner.RoundFractionalValues = 'on';
            app.ImportFunctiontoAddtoSpinner.Value = 1;
            app.SelectImportLoadSaveSlotButtonGroup = uibuttongroup(app.ImportDataTab);
            app.SelectImportLoadSaveSlotButtonGroup.Title = 'Select Import Load/Save Slot';
            app.Import1Button = uiradiobutton(app.SelectImportLoadSaveSlotButtonGroup);
            app.Import1Button.Text = 'Import 1';
            app.Import1Button.Value = true;
            app.Import2Button = uiradiobutton(app.SelectImportLoadSaveSlotButtonGroup);
            app.Import2Button.Text = 'Import 2';
            app.Import3Button = uiradiobutton(app.SelectImportLoadSaveSlotButtonGroup);
            app.Import3Button.Text = 'Import 3';
            app.Import4Button = uiradiobutton(app.SelectImportLoadSaveSlotButtonGroup);
            app.Import4Button.Text = 'Import 4';
            app.Import5Button = uiradiobutton(app.SelectImportLoadSaveSlotButtonGroup);
            app.Import5Button.Text = 'Import 5';
            app.Import6Button = uiradiobutton(app.SelectImportLoadSaveSlotButtonGroup);
            app.Import6Button.Text = 'Import 6';
            app.SaveButton = uibutton(app.SelectImportLoadSaveSlotButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save data from Table Workspace into selected Import Slot.'};
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.SelectImportLoadSaveSlotButtonGroup, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load Data from selected Import Slot into Table Workspace'};
            app.LoadButton.Text = 'Load';
            app.DirectivityCalculationsWillOnlyWorkwithLabel = uilabel(app.ImportDataTab);
            app.DirectivityCalculationsWillOnlyWorkwithLabel.Interpreter = 'latex';
            app.DirectivityCalculationsWillOnlyWorkwithLabel.Text = {'Directivity Calculation Integrals'; 'will only work with '; 'theta ranging:'; '(-180):($\Delta\text{theta}$):(180 - $\Delta\text{theta}$) '; 'or '; '(-90):($\Delta\text{theta}$):(90 - $\Delta\text{theta}$)'; 'or'; '(0):($\Delta\text{theta}$):(360 - $\Delta\text{theta}$)'; ''; 'Invalid Directivities'; 'will default to -1'; ''; 'Use the Insert/Remove First/Last '; 'Element Buttons to Properly '; 'size the data'};
            app.PerformOperationsonTableDataPanel = uipanel(app.ImportDataTab);
            app.PerformOperationsonTableDataPanel.Title = 'Perform Operations on Table Data';
            app.ScaleByMaxValueButton = uibutton(app.PerformOperationsonTableDataPanel, 'push');
            app.ScaleByMaxValueButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleByMaxValueButtonPushed, true);
            app.ScaleByMaxValueButton.Tooltip = {'Every element of the Directional Factor data will be divided by the maximum magnitude value. (Normalized to 1)'};
            app.ScaleByMaxValueButton.Text = 'Scale By Max Value';
            app.TakeAbsoluteValueButton = uibutton(app.PerformOperationsonTableDataPanel, 'push');
            app.TakeAbsoluteValueButton.ButtonPushedFcn = createCallbackFcn(app, @TakeAbsoluteValueButtonPushed, true);
            app.TakeAbsoluteValueButton.Tooltip = {'Directional Factor Values will be made positive'};
            app.TakeAbsoluteValueButton.Text = 'Take Absolute Value';
            app.InsertFirstElementButton = uibutton(app.PerformOperationsonTableDataPanel, 'push');
            app.InsertFirstElementButton.ButtonPushedFcn = createCallbackFcn(app, @InsertFirstElementButtonPushed, true);
            app.InsertFirstElementButton.Tooltip = {'Empty Element will be inserted at top of Table'};
            app.InsertFirstElementButton.Text = 'Insert First Element';
            app.InsertLastElementButton = uibutton(app.PerformOperationsonTableDataPanel, 'push');
            app.InsertLastElementButton.ButtonPushedFcn = createCallbackFcn(app, @InsertLastElementButtonPushed, true);
            app.InsertLastElementButton.Tooltip = {'Empty Element will be inserted at bottom of Table'};
            app.InsertLastElementButton.Text = 'Insert Last Element';
            app.RemoveLastElementButton = uibutton(app.PerformOperationsonTableDataPanel, 'push');
            app.RemoveLastElementButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastElementButtonPushed, true);
            app.RemoveLastElementButton.Tooltip = {'Element will be deleted from bottom of Table'};
            app.RemoveLastElementButton.Text = 'Remove Last Element';
            app.RemoveFirstElementButton = uibutton(app.PerformOperationsonTableDataPanel, 'push');
            app.RemoveFirstElementButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFirstElementButtonPushed, true);
            app.RemoveFirstElementButton.Tooltip = {'Element will be deleted from top of Table'};
            app.RemoveFirstElementButton.Text = 'Remove First Element';
            app.TextArea = uitextarea(app.ImportDataTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Table Workspace'};
            app.HelpandInstructionsTab = uitab(app.TabGroup);
            app.HelpandInstructionsTab.Tooltip = {'Tips and Tricks for using this program'};
            app.HelpandInstructionsTab.Title = 'Help and Instructions';
            app.TabGroup2 = uitabgroup(app.HelpandInstructionsTab);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.Function1EditField = uieditfield(app.LeftPanel, 'text');
            app.Function1EditField.ValueChangedFcn = createCallbackFcn(app, @Function1EditFieldValueChanged, true);
            app.Function1EditField.Placeholder = '(1+cos(theta))/2';
            app.Function1EditField.Value = '(1+cos(theta))/2';
            app.Function2EditField = uieditfield(app.LeftPanel, 'text');
            app.Function2EditField.ValueChangedFcn = createCallbackFcn(app, @Function2EditFieldValueChanged, true);
            app.Function2EditField.Placeholder = '(1+cos(theta))/2';
            app.Function2EditField.Value = 'cos(theta).^2';
            app.Function3EditField = uieditfield(app.LeftPanel, 'text');
            app.Function3EditField.ValueChangedFcn = createCallbackFcn(app, @Function3EditFieldValueChanged, true);
            app.Function3EditField.Placeholder = '(1+cos(theta))/2';
            app.Function3EditField.Value = 'cos(theta).^3';
            app.Function4EditField = uieditfield(app.LeftPanel, 'text');
            app.Function4EditField.ValueChangedFcn = createCallbackFcn(app, @Function4EditFieldValueChanged, true);
            app.Function4EditField.Placeholder = '(1+cos(theta))/2';
            app.Function4EditField.Value = 'cos(theta).^4';
            app.Function1Button = uibutton(app.LeftPanel, 'state');
            app.Function1Button.ValueChangedFcn = createCallbackFcn(app, @Function1ButtonValueChanged, true);
            app.Function1Button.Tooltip = {'Toggle the visibility of this function in the Plot'};
            app.Function1Button.Text = 'Function 1';
            app.Function1Button.Value = true;
            app.Function3Button = uibutton(app.LeftPanel, 'state');
            app.Function3Button.ValueChangedFcn = createCallbackFcn(app, @Function3ButtonValueChanged, true);
            app.Function3Button.Tooltip = {'Toggle the visibility of this function in the Plot'};
            app.Function3Button.Text = 'Function 3';
            app.Function2Button = uibutton(app.LeftPanel, 'state');
            app.Function2Button.ValueChangedFcn = createCallbackFcn(app, @Function2ButtonValueChanged, true);
            app.Function2Button.Tooltip = {'Toggle the visibility of this function in the Plot'};
            app.Function2Button.Text = 'Function 2';
            app.Function4Button = uibutton(app.LeftPanel, 'state');
            app.Function4Button.ValueChangedFcn = createCallbackFcn(app, @Function4ButtonValueChanged, true);
            app.Function4Button.Tooltip = {'Toggle the visibility of this function in the Plot'};
            app.Function4Button.Text = 'Function 4';
            app.GeneratePlotTemplateButton = uibutton(app.LeftPanel, 'push');
            app.GeneratePlotTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotTemplateButtonPushed, true);
            app.GeneratePlotTemplateButton.Tooltip = {'Generate the background of plot to see if current settings are correct.'};
            app.GeneratePlotTemplateButton.Text = 'Generate Plot Template';
            app.PlotFunctionButton = uibutton(app.LeftPanel, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Tooltip = {'Plot the selected functions data using the currently selected options.'};
            app.PlotFunctionButton.Text = 'Plot Function';
            app.PlotTemplateLamp = uilamp(app.LeftPanel);
            app.PlotTemplateLamp.Enable = 'off';
            app.PlotFunctionLamp = uilamp(app.LeftPanel);
            app.PlotFunctionLamp.Enable = 'off';
            app.Function5EditField = uieditfield(app.LeftPanel, 'text');
            app.Function5EditField.ValueChangedFcn = createCallbackFcn(app, @Function5EditFieldValueChanged, true);
            app.Function5EditField.ValueChangingFcn = createCallbackFcn(app, @Function5EditFieldValueChanging, true);
            app.Function5EditField.Placeholder = '(1+cos(theta))/2';
            app.Function5EditField.Value = 'cos(theta).^5';
            app.Function6EditField = uieditfield(app.LeftPanel, 'text');
            app.Function6EditField.ValueChangedFcn = createCallbackFcn(app, @Function6EditFieldValueChanged, true);
            app.Function6EditField.Placeholder = '(1+cos(theta))/2';
            app.Function6EditField.Value = 'cos(theta).^6';
            app.Function5Button = uibutton(app.LeftPanel, 'state');
            app.Function5Button.Tooltip = {'Toggle the visibility of this function in the Plot'};
            app.Function5Button.Text = 'Function 5';
            app.Function6Button = uibutton(app.LeftPanel, 'state');
            app.Function6Button.Tooltip = {'Toggle the visibility of this function in the Plot'};
            app.Function6Button.Text = 'Function 6';
            app.OpenExternalFigureWindowforSavingCopyingImageButton = uibutton(app.LeftPanel, 'push');
            app.OpenExternalFigureWindowforSavingCopyingImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExternalFigureWindowforSavingCopyingImageButtonPushed, true);
            app.OpenExternalFigureWindowforSavingCopyingImageButton.Tooltip = {'If this doesn''t work, try hitting Windows Key + S at the same time!'};
            app.OpenExternalFigureWindowforSavingCopyingImageButton.Text = 'Open External Figure Window for Saving/Copying Image';
            app.LegendEntryEditFieldLabel = uilabel(app.LeftPanel);
            app.LegendEntryEditFieldLabel.Tooltip = {'Specify the name of this function in the Plot''s Legend'};
            app.LegendEntryEditFieldLabel.Text = 'Legend Entry';
            app.LegendEntryEditField_1 = uieditfield(app.LeftPanel, 'text');
            app.LegendEntryEditField_1.Placeholder = 'Function 1';
            app.LegendEntryEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.LegendEntryEditField_2.Placeholder = 'Function 2';
            app.LegendEntryEditField_3 = uieditfield(app.LeftPanel, 'text');
            app.LegendEntryEditField_3.Placeholder = 'Function 3';
            app.LegendEntryEditField_4 = uieditfield(app.LeftPanel, 'text');
            app.LegendEntryEditField_4.Placeholder = 'Function 4';
            app.LegendEntryEditField_6 = uieditfield(app.LeftPanel, 'text');
            app.LegendEntryEditField_6.Placeholder = 'Function 6';
            app.LegendEntryEditField_5 = uieditfield(app.LeftPanel, 'text');
            app.LegendEntryEditField_5.Placeholder = 'Function 5';
            app.EquationLabel = uilabel(app.LeftPanel);
            app.EquationLabel.Tooltip = {'Manually type a function to plot in terms of theta (in radians). absolute value of this function will be taken automatically'};
            app.EquationLabel.Text = 'Equation';
            app.DirectivitiesEditFieldLabel = uilabel(app.LeftPanel);
            app.DirectivitiesEditFieldLabel.Tooltip = {'Display the Directivity computed for each (PLOTTED) function. Value is -1 if calculation was deemed invalid'};
            app.DirectivitiesEditFieldLabel.Text = 'Directivities';
            app.DirectivitiesEditField_1 = uieditfield(app.LeftPanel, 'numeric');
            app.DirectivitiesEditField_1.Value = -1;
            app.DirectivitiesEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.DirectivitiesEditField_2.Value = -1;
            app.DirectivitiesEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.DirectivitiesEditField_3.Value = -1;
            app.DirectivitiesEditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.DirectivitiesEditField_4.Value = -1;
            app.DirectivitiesEditField_5 = uieditfield(app.LeftPanel, 'numeric');
            app.DirectivitiesEditField_5.Value = -1;
            app.DirectivitiesEditField_6 = uieditfield(app.LeftPanel, 'numeric');
            app.DirectivitiesEditField_6.Value = -1;
            app.LineTypeDropDownLabel = uilabel(app.LeftPanel);
            app.LineTypeDropDownLabel.Tooltip = {'Specify the style of this function''s plot'};
            app.LineTypeDropDownLabel.Text = 'Line Type';
            app.LineTypeDropDown_1 = uidropdown(app.LeftPanel);
            app.LineTypeDropDown_1.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dotted'};
            app.LineTypeDropDown_1.Value = 'Solid';
            app.LineTypeDropDown_2 = uidropdown(app.LeftPanel);
            app.LineTypeDropDown_2.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dotted'};
            app.LineTypeDropDown_2.Value = 'Solid';
            app.LineTypeDropDown_3 = uidropdown(app.LeftPanel);
            app.LineTypeDropDown_3.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dotted'};
            app.LineTypeDropDown_3.Value = 'Solid';
            app.LineTypeDropDown_4 = uidropdown(app.LeftPanel);
            app.LineTypeDropDown_4.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dotted'};
            app.LineTypeDropDown_4.Value = 'Solid';
            app.LineTypeDropDown_5 = uidropdown(app.LeftPanel);
            app.LineTypeDropDown_5.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dotted'};
            app.LineTypeDropDown_5.Value = 'Solid';
            app.LineTypeDropDown_6 = uidropdown(app.LeftPanel);
            app.LineTypeDropDown_6.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dotted'};
            app.LineTypeDropDown_6.Value = 'Solid';
            app.LineColorDropDownLabel = uilabel(app.LeftPanel);
            app.LineColorDropDownLabel.Tooltip = {'Specify the Color of this function''s plot'};
            app.LineColorDropDownLabel.Text = 'Line Color';
            app.LineColorDropDown_1 = uidropdown(app.LeftPanel);
            app.LineColorDropDown_1.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Gray', 'Black'};
            app.LineColorDropDown_1.Value = 'Red';
            app.LineColorDropDown_2 = uidropdown(app.LeftPanel);
            app.LineColorDropDown_2.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Gray', 'Black'};
            app.LineColorDropDown_2.Value = 'Green';
            app.LineColorDropDown_3 = uidropdown(app.LeftPanel);
            app.LineColorDropDown_3.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Gray', 'Black'};
            app.LineColorDropDown_3.Value = 'Blue';
            app.LineColorDropDown_4 = uidropdown(app.LeftPanel);
            app.LineColorDropDown_4.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Gray', 'Black'};
            app.LineColorDropDown_4.Value = 'Cyan';
            app.LineColorDropDown_5 = uidropdown(app.LeftPanel);
            app.LineColorDropDown_5.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Gray', 'Black'};
            app.LineColorDropDown_5.Value = 'Magenta';
            app.LineColorDropDown_6 = uidropdown(app.LeftPanel);
            app.LineColorDropDown_6.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Gray', 'Black'};
            app.LineColorDropDown_6.Value = 'Yellow';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Directional Factor')
            app.UIAxes.XLim = [-1.1 1.1];
            app.UIAxes.YLim = [-0.6 1.1];
            app.Hyperlink_4 = uihyperlink(app.RightPanel);
            app.Hyperlink_4.URL = 'https://github.com/mbisbano1/MatlabSmartPolarPlots/blob/main/README.md';
            app.Hyperlink_4.Text = 'Help';
            % Show the figure after all components are created
            app.SmartPolarPlotsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DirectionalFactorPolarPlotsV2_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmartPolarPlotsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmartPolarPlotsUIFigure)
        end
    end
end